classdef ProyectoVolcan2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Simulador                       matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        SimulacionTab                   matlab.ui.container.Tab
        mLabel_9                        matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        AlturaVolcanEditField_4Label    matlab.ui.control.Label
        AlturaVolcanEditField           matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        tEditField                      matlab.ui.control.NumericEditField
        SimularButton                   matlab.ui.control.Button
        ExportardatosButton             matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Proyectil1Tab                   matlab.ui.container.Tab
        msLabel_3                       matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        CoeficientefriccionEditFieldLabel  matlab.ui.control.Label
        CoeficientefriccionEditField    matlab.ui.control.NumericEditField
        VelocidadinicialEditFieldLabel  matlab.ui.control.Label
        VelocidadinicialEditField       matlab.ui.control.NumericEditField
        AnguloEditFieldLabel            matlab.ui.control.Label
        AnguloEditField                 matlab.ui.control.NumericEditField
        MasaproyectilEditFieldLabel     matlab.ui.control.Label
        MasaproyectilEditField          matlab.ui.control.NumericEditField
        Proyectil2Tab                   matlab.ui.container.Tab
        msLabel_5                       matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        CoeficientefriccionEditField_2Label  matlab.ui.control.Label
        CoeficientefriccionEditField2   matlab.ui.control.NumericEditField
        VelocidadinicialEditField_2Label  matlab.ui.control.Label
        VelocidadinicialEditField2      matlab.ui.control.NumericEditField
        AnguloEditField_2Label          matlab.ui.control.Label
        AnguloEditField2                matlab.ui.control.NumericEditField
        MasaproyectilEditField_2Label   matlab.ui.control.Label
        MasaproyectilEditField2         matlab.ui.control.NumericEditField
        Proyectil3Tab                   matlab.ui.container.Tab
        msLabel_6                       matlab.ui.control.Label
        kgLabel_3                       matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        CoeficientefriccionEditField_3Label  matlab.ui.control.Label
        CoeficientefriccionEditField3   matlab.ui.control.NumericEditField
        VelocidadinicialEditField_3Label  matlab.ui.control.Label
        VelocidadinicialEditField3      matlab.ui.control.NumericEditField
        AnguloEditField_3Label          matlab.ui.control.Label
        AnguloEditField3                matlab.ui.control.NumericEditField
        MasaproyectilEditField_3Label   matlab.ui.control.Label
        MasaproyectilEditField3         matlab.ui.control.NumericEditField
        ResultadosTab                   matlab.ui.container.Tab
        AlturaMaximaLabel               matlab.ui.control.Label
        VelocidadImpactoLabel           matlab.ui.control.Label
        PuntodeimpactoxLabel            matlab.ui.control.Label
        ResultadosLabel                 matlab.ui.control.Label
        AlturaMaxima                    matlab.ui.control.NumericEditField
        VelocidadImpacto                matlab.ui.control.NumericEditField
        mLabel_5                        matlab.ui.control.Label
        msLabel_4                       matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        Impacto                         matlab.ui.control.NumericEditField
        AlturaMaxima_2                  matlab.ui.control.NumericEditField
        VelocidadImpacto_2              matlab.ui.control.NumericEditField
        Impacto_2                       matlab.ui.control.NumericEditField
        AlturaMaxima_3                  matlab.ui.control.NumericEditField
        VelocidadImpacto_3              matlab.ui.control.NumericEditField
        Impacto_3                       matlab.ui.control.NumericEditField
        Proyectil1Label                 matlab.ui.control.Label
        Proyectil2Label                 matlab.ui.control.Label
        Proyectil3Label                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Simulador,'center');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Simulador.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {532, 532};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {540, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            set(app.UITable,'Data',[])
        
            %%Variables
            dt = app.tEditField.Value;%%Delta t
            
            %%Proyectil 1 y 2
            x0 = 0;%%X inicial
            y0 = app.AlturaVolcanEditField.Value;%%Y inicial
            v = app.VelocidadinicialEditField.Value;%%Velocidad inicial
            a = app.AnguloEditField.Value*(pi/180);%%Angulo inicial
            m = app.MasaproyectilEditField.Value;%%Masa
            cfri = app.CoeficientefriccionEditField.Value*(-1);%%coeficiente de cuerpo circular en el aire
            %%Proyectil 3 y 4
            x02 = 0;%%X inicial
            y02 = app.AlturaVolcanEditField.Value;%%Y inicial
            v2 = app.VelocidadinicialEditField2.Value;%%Velocidad inicial
            a2 = app.AnguloEditField2.Value*(pi/180);%%Angulo inicial
            m2 = app.MasaproyectilEditField2.Value;%%Masa
            cfri2 = app.CoeficientefriccionEditField2.Value*(-1);%%coeficiente de cuerpo circular en el aire
            %%Proyectil 5 y 6
            x03 = 0;%%X inicial
            y03 = app.AlturaVolcanEditField.Value;%%Y inicial
            v3 = app.VelocidadinicialEditField3.Value;%%Velocidad inicial
            a3 = app.AnguloEditField3.Value*(pi/180);%%Angulo inicial
            m3 = app.MasaproyectilEditField3.Value;%%Masa
            cfri3 = app.CoeficientefriccionEditField3.Value*(-1);%%coeficiente de cuerpo circular en el aire
            
            %%Variables finales 
            AlturaMaxima = 0;
            AlturaMaxima2 = 0;
            AlturaMaxima3 = 0;
            VelocidadImpacto = 0;
            PuntoImpacto = "[0,0]";
            continue12 = true;
            continue34 = true;
            continue56 = true;
            
            %%Constantes 
            g = -9.81;%% gravedad
            
            %%Escala de la grafica
            xmove = 1000;
            maxheight = ((v^2*(sin(a))^2)/abs(2*g))+y0+500;
            maxheight2 = ((v2^2*(sin(a2)^2)/abs(2*g))+y02+500);
            maxheight3 = ((v3^2*(sin(a3)^2)/abs(2*g))+y03+500);
            maxs = [maxheight,maxheight2,maxheight3];
            maxheight = max(maxs);
            
            %%Image background
            img = imread('background.png');
            imagesc(app.UIAxes,img);
            img2 = flip(img ,1);
            imagesc(app.UIAxes, [-400 xmove], [-200 maxheight], img2);
            ax = app.UIAxes;
            ax.YDir = 'normal';
            
            %%Modelado
            i = 1;
            graphc = true;
            %Proyectil 1 y 2
            vx0 = v*cos(a);
            vy0 = v*sin(a);
            %Proyectil 3 y 4
            vx02 = v2*cos(a2);
            vy02 = v2*sin(a2);
            %Proyectil 5 y 6
            vx03 = v3*cos(a3);
            vy03 = v3*sin(a3);
            
            
            %%Caso 0
            %SIN FRICCION
                %Proyectil 1 y 2
                vx1 = vx0;
                vy1 = vy0;
                x1 = x0;
                y1 = y0;
                ay1 =0;
                %Proyectil 3 y 4
                vx3 = vx02;
                vy3 = vy02;
                x3 = x02;
                y3 = y02;
                ay3 =0;
                %Proyectil 5 y 6
                vx5 = vx03;
                vy5 = vy03;
                x5 = x03;
                y5 = y03;
                ay5 =0;
            
            %CON FRICCION
                %Proyectil 1 y 2
                vx2 = vx0;
                vy2 = vy0;
                fx = cfri*vx2;
                fy = cfri*vy2;
                ax = 0;
                ay = 0;
                x2 = x0;
                y2 = y0;
            
                fxa = fx;
                fya = fy;
                vx2a = vx2;
                vy2a = vy2;
                %Proyectil 3 y 4
                vx4 = vx02;
                vy4 = vy02;
                fx4 = cfri2*vx4;
                fy4 = cfri2*vy4;
                ax4 = 0;
                ay4 = 0;
                x4 = x02;
                y4 = y02;
            
                fxa4 = fx4;
                fya4 = fy4;
                vx4a = vx4;
                vy4a = vy4;
                %Proyectil 5 y 6
                vx6 = vx03;
                vy6 = vy03;
                fx6 = cfri3*vx6;
                fy6 = cfri3*vy6;
                ax6 = 0;
                ay6 = 0;
                x6 = x03;
                y6 = y03;
            
                fxa6 = fx6;
                fya6 = fy6;
                vx6a = vx6;
                vy6a = vy6;
                
            while graphc
                hold(app.UIAxes,"on");
                %%CASO 1
                if i == 1
                %%SF
                    %Caso 1 y 2
                    ay1 =g;
                    vx1 = vx1; %constante
                    vy1 = vy1;
                    x1 = x1+vx1*dt;
                    y1 = y1+vy1*dt;
                    
                    vy1a = vy1;
                    y1a = y1;
                    %Caso 3 y 4
                    ay3 =g;
                    vx3 = vx3; %constante
                    vy3 = vy3;
                    x3 = x3+vx3*dt;
                    y3 = y3+vy3*dt;
                    
                    vy3a = vy3;
                    y3a = y3;
                    %Caso 5 y 6
                    ay5 =g;
                    vx5 = vx5; %constante
                    vy5 = vy5;
                    x5 = x5+vx5*dt;
                    y5 = y5+vy5*dt;
                    
                    vy5a = vy5;
                    y5a = y5;
                    
                %%CF
                    %Caso 1 y 2
                    vx2 = vx2+ax*dt;
                    vy2 = vy2+ay*dt;
                    fx = cfri*vx2;
                    fy = cfri*vy2;
                    ax = fxa/m;
                    ay = fya/m+g;
                    x2 = x2+vx2a*dt;
                    y2 = y2+vy2a*dt;
                    
                    fxa = fx;
                    fya = fy;
                    vx2a = vx2;
                    vy2a = vy2;
                    %Caso 3 y 4
                    vx4 = vx4+ax4*dt;
                    vy4 = vy4+ay4*dt;
                    fx4 = cfri2*vx4;
                    fy4 = cfri2*vy4;
                    ax4 = fxa4/m2;
                    ay4 = fya4/m2+g;
                    x4 = x4+vx4a*dt;
                    y4 = y4+vy4a*dt;
                    
                    fxa4 = fx4;
                    fya4 = fy4;
                    vx4a = vx4;
                    vy4a = vy4;
                    %Caso 5 y 6
                    vx6 = vx6+ax6*dt;
                    vy6 = vy6+ay6*dt;
                    fx6 = cfri3*vx6;
                    fy6 = cfri3*vy6;
                    ax6 = fxa6/m3;
                    ay6 = fya6/m3+g;
                    x6 = x6+vx6a*dt;
                    y6 = y6+vy6a*dt;
                    
                    fxa6 = fx6;
                    fya6 = fy6;
                    vx6a = vx6;
                    vy6a = vy6;
                    
                end
                %%SF
                    %Caso 1 y 2
                    if continue12 == true
                        ay1 = g;
                        vx1 = vx1; %constante, aqui no va dt
                        vy1 = vy1+ay1*dt;
                        x1 = x1+vx1*dt;
                        y1 = y1a+vy1a*dt;
                        
                        vy1a = vy1;
                        y1a = y1;
                        
                    end
                    
                    %Caso 3 y 4
                    if continue34 == true
                        ay3 = g;
                        vx3 = vx3; %constante, aqui no va dt
                        vy3 = vy3+ay3*dt;
                        x3 = x3+vx3*dt;
                        y3 = y3a+vy3a*dt;
                    
                        vy3a = vy3;
                        y3a = y3;
                    end
                    %Caso 5 y 6
                    if continue56 == true
                        ay5 = g;
                        vx5 = vx5; %constante, aqui no va dt
                        vy5 = vy5+ay5*dt;
                        x5 = x5+vx5*dt;
                        y5 = y5a+vy5a*dt;
                        
                        vy5a = vy5;
                        y5a = y5;
                    end
                    
                %%CF
                    %Caso 1 y 2
                    if continue12 == true
                        vx2 = vx2+ax*dt;
                        vy2 = vy2+ay*dt;
                        fx = cfri*vx2;
                        fy = cfri*vy2;
                        ax = fxa/m;
                        ay = fya/m+g;
                        
                        y2a = y2;
                        
                        x2 = x2+vx2a*dt;
                        y2 = y2+vy2a*dt;
                        
                        fxa = fx;
                        fya = fy;
                        vx2a = vx2;
                        vy2a = vy2;
                        if y2 <= 0
                            continue12 = false;
                        end
                    end
                    %Caso 3 y 4
                    if continue34 == true
                        vx4 = vx4+ax4*dt;
                        vy4 = vy4+ay4*dt;
                        fx4 = cfri2*vx4;
                        fy4 = cfri2*vy4;
                        ax4 = fxa4/m2;
                        ay4 = fya4/m2+g;
                        
                        y4a = y4;
                        
                        x4 = x4+vx4a*dt;
                        y4 = y4+vy4a*dt;
                        
                        fxa4 = fx4;
                        fya4 = fy4;
                        vx4a = vx4;
                        vy4a = vy4;
                        if y4 <=0
                            continue34 = false;
                        end
                    end
                    %Caso 5 y 6
                    if continue56 == true
                        vx6 = vx6+ax6*dt;
                        vy6 = vy6+ay6*dt;
                        fx6 = cfri3*vx6;
                        fy6 = cfri3*vy6;
                        ax6 = fxa6/m3;
                        ay6 = fya6/m3+g;
                        
                        y6a = y4;
                        
                        x6 = x6+vx6a*dt;
                        y6 = y6+vy6a*dt;
                        
                        fxa6 = fx6;
                        fya6 = fy6;
                        vx6a = vx6;
                        vy6a = vy6;
                        if y6<=0
                            continue56 = false;
                        end
                    end
                
                 if y2 >= AlturaMaxima 
                     AlturaMaxima = y2;
                 end
                 if y4 >= AlturaMaxima2
                     AlturaMaxima2 = y4;
                 end
                 if y6 >= AlturaMaxima3
                     AlturaMaxima3 =y6;
                 end

                data=get(app.UITable, 'data'); % Get data as a string
                data(end+1,:)=[i x1 y1 x2 y2 x3 y3 x4 y4 x5 y5 x6 y6 vx1 vy1 vx2 vy2 vx3 vy3 vx4 vy4 vx5 vy5 vx6 vy6]; % Add new row of 0's as a cell type
                set(app.UITable, 'data', data);
                            
                ylim(app.UIAxes,[0 maxheight]);
                xlim(app.UIAxes,[0 xmove]);
                
                %Proyectil 1 y 2
                if y2 >=0
                    plot(app.UIAxes,x1,y1,'-or');
                    plot(app.UIAxes,x2,y2,'-ob');
                end
                %Proyectil 3 y 4
                if y4 >=0
                    plot(app.UIAxes,x3,y3,'-og');
                    plot(app.UIAxes,x4,y4,'-oc');
                end
                %Proyectil 
                if y6 >= 0
                    plot(app.UIAxes,x5,y5,'-om');
                    plot(app.UIAxes,x6,y6,'-oy');
                end
                
                title(app.UIAxes,'Modelado de la posicion de un proyectil volcanico');
                xlabel(app.UIAxes,'Posicion en x (m)');
                ylabel(app.UIAxes,'Posicion en y (m)');
                legend(app.UIAxes,{'Sin friccion P1','Con friccion P1','Sin friccion P2','Con friccion P2','Sin friccion P3','Con friccion P3'},'Location','southwest');
                    
                %%disp(vy);
                pause(0.1)
                hold(app.UIAxes,'off');
                
                i=i+dt;
                if continue12 == false && continue34 == false && continue56 == false
                    graphc = false;
                    %proyectil 1 y 2
                    app.AlturaMaxima.Value = AlturaMaxima;
                    app.VelocidadImpacto.Value = sqrt(vx2^2+vy2^2);
                    app.Impacto.Value = x2;
                    %Proyectil 3 y 4
                    app.AlturaMaxima_2.Value = AlturaMaxima2;
                    app.VelocidadImpacto_2.Value = sqrt(vx4^2+vy4^2);
                    app.Impacto_2.Value = x4;
                    %Proyectil 4 y 5
                    app.AlturaMaxima_3.Value = AlturaMaxima3;
                    app.VelocidadImpacto_3.Value = sqrt(vx6^2+vy6^2);
                    app.Impacto_3.Value = x6;
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            decodedValue = "Proyecto realizado para Modelacion computacional del movimiento Equipo:Abraham Cepeda, A00827666 Alejandro Gonzalez, A01570396 Axel Villanueva, A00828073 Hernan Salinas, A01570409 Juan Carlos Garfias, A01652138 (ITESM, 2019)";
            
            fig = figure("Name",'Informacion');
            hPan = uipanel(fig,'Units','normalized');

            uicontrol(hPan, 'Style','text', 'HorizontalAlignment','center', ...
            'FontSize',10, 'Units','normalized', 'Position',[0.2 0.4 0.6 0.2], ...
            'String',decodedValue);
        end

        % Button pushed function: ExportardatosButton
        function ExportardatosButtonPushed(app, event)
            data = get(app.UITable, 'data');
            columns = get(app.UITable, 'columnname');
            columns = reshape(columns, [1,25]);
            filename = 'Datos_proyectil.xls';
            xlswrite(filename, data, 1, 'A2');
            xlswrite(filename, columns, 1, 'A1:Y1');
            command = 'start excel Datos_proyectil.xls';
            command2 = 'open Datos_proyectil.xls';
            system(command);
            system(command2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Simulador = uifigure('Visible', 'off');
            app.Simulador.Name = 'UI Figure';
            app.Simulador.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Simulador);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Modelado de la posicion de un proyectil volcanico')
            xlabel(app.UIAxes, 'Posicion en x (m)')
            ylabel(app.UIAxes, 'Posicion en y (m)')
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Tiempo (s)'; 'x1'; 'y1'; 'x2'; 'y2'; 'x3'; 'y3'; 'x4'; 'y4'; 'x5'; 'y5'; 'x6'; 'y6'; 'vx1'; 'vy1'; 'vx2'; 'vy2'; 'vx3'; 'vy3'; 'vx4'; 'vy4'; 'vx5'; 'vy5'; 'vx6'; 'vy6'};
            app.UITable.RowName = {};
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SimulacionTab = uitab(app.TabGroup);
            app.SimulacionTab.Title = 'Simulacion';
            app.mLabel_9 = uilabel(app.SimulacionTab);
            app.mLabel_9.Text = 'm';
            app.sLabel_2 = uilabel(app.SimulacionTab);
            app.sLabel_2.Text = 's';
            app.AlturaVolcanEditField_4Label = uilabel(app.SimulacionTab);
            app.AlturaVolcanEditField_4Label.Text = 'Altura Volcan';
            app.AlturaVolcanEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.AlturaVolcanEditField.ValueDisplayFormat = '%.3f';
            app.AlturaVolcanEditField.Value = 2547.429;
            app.tEditFieldLabel = uilabel(app.SimulacionTab);
            app.tEditFieldLabel.Text = 'Δt';
            app.tEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.tEditField.ValueDisplayFormat = '%.3f';
            app.tEditField.Value = 1;
            app.SimularButton = uibutton(app.SimulacionTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.ExportardatosButton = uibutton(app.SimulacionTab, 'push');
            app.ExportardatosButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosButtonPushed, true);
            app.ExportardatosButton.Text = 'Exportar datos';
            app.Button = uibutton(app.SimulacionTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Proyectil1Tab = uitab(app.TabGroup);
            app.Proyectil1Tab.Title = 'Proyectil 1';
            app.msLabel_3 = uilabel(app.Proyectil1Tab);
            app.msLabel_3.Text = 'm/s';
            app.kgLabel = uilabel(app.Proyectil1Tab);
            app.kgLabel.Text = 'kg';
            app.Label_4 = uilabel(app.Proyectil1Tab);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.Proyectil1Tab);
            app.Label_5.Text = 'μ';
            app.CoeficientefriccionEditFieldLabel = uilabel(app.Proyectil1Tab);
            app.CoeficientefriccionEditFieldLabel.Text = 'Coeficiente friccion';
            app.CoeficientefriccionEditField = uieditfield(app.Proyectil1Tab, 'numeric');
            app.CoeficientefriccionEditField.Value = 0.47;
            app.VelocidadinicialEditFieldLabel = uilabel(app.Proyectil1Tab);
            app.VelocidadinicialEditFieldLabel.Text = 'Velocidad inicial';
            app.VelocidadinicialEditField = uieditfield(app.Proyectil1Tab, 'numeric');
            app.VelocidadinicialEditField.ValueDisplayFormat = '%.3f';
            app.VelocidadinicialEditField.Value = 114.37;
            app.AnguloEditFieldLabel = uilabel(app.Proyectil1Tab);
            app.AnguloEditFieldLabel.Text = 'Angulo ';
            app.AnguloEditField = uieditfield(app.Proyectil1Tab, 'numeric');
            app.AnguloEditField.ValueDisplayFormat = '%.3f';
            app.AnguloEditField.Value = 85;
            app.MasaproyectilEditFieldLabel = uilabel(app.Proyectil1Tab);
            app.MasaproyectilEditFieldLabel.Text = 'Masa proyectil';
            app.MasaproyectilEditField = uieditfield(app.Proyectil1Tab, 'numeric');
            app.MasaproyectilEditField.ValueDisplayFormat = '%.3f';
            app.MasaproyectilEditField.Value = 100;
            app.Proyectil2Tab = uitab(app.TabGroup);
            app.Proyectil2Tab.Title = 'Proyectil 2';
            app.msLabel_5 = uilabel(app.Proyectil2Tab);
            app.msLabel_5.Text = 'm/s';
            app.kgLabel_2 = uilabel(app.Proyectil2Tab);
            app.kgLabel_2.Text = 'kg';
            app.Label_6 = uilabel(app.Proyectil2Tab);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.Proyectil2Tab);
            app.Label_7.Text = 'μ';
            app.CoeficientefriccionEditField_2Label = uilabel(app.Proyectil2Tab);
            app.CoeficientefriccionEditField_2Label.Text = 'Coeficiente friccion';
            app.CoeficientefriccionEditField2 = uieditfield(app.Proyectil2Tab, 'numeric');
            app.CoeficientefriccionEditField2.Value = 0.47;
            app.VelocidadinicialEditField_2Label = uilabel(app.Proyectil2Tab);
            app.VelocidadinicialEditField_2Label.Text = 'Velocidad inicial';
            app.VelocidadinicialEditField2 = uieditfield(app.Proyectil2Tab, 'numeric');
            app.VelocidadinicialEditField2.ValueDisplayFormat = '%.3f';
            app.VelocidadinicialEditField2.Value = 105.7;
            app.AnguloEditField_2Label = uilabel(app.Proyectil2Tab);
            app.AnguloEditField_2Label.Text = 'Angulo ';
            app.AnguloEditField2 = uieditfield(app.Proyectil2Tab, 'numeric');
            app.AnguloEditField2.ValueDisplayFormat = '%.3f';
            app.AnguloEditField2.Value = 87;
            app.MasaproyectilEditField_2Label = uilabel(app.Proyectil2Tab);
            app.MasaproyectilEditField_2Label.Text = 'Masa proyectil';
            app.MasaproyectilEditField2 = uieditfield(app.Proyectil2Tab, 'numeric');
            app.MasaproyectilEditField2.ValueDisplayFormat = '%.3f';
            app.MasaproyectilEditField2.Value = 200;
            app.Proyectil3Tab = uitab(app.TabGroup);
            app.Proyectil3Tab.Title = 'Proyectil 3';
            app.msLabel_6 = uilabel(app.Proyectil3Tab);
            app.msLabel_6.Text = 'm/s';
            app.kgLabel_3 = uilabel(app.Proyectil3Tab);
            app.kgLabel_3.Text = 'kg';
            app.Label_8 = uilabel(app.Proyectil3Tab);
            app.Label_8.Text = '°';
            app.Label_9 = uilabel(app.Proyectil3Tab);
            app.Label_9.Text = 'μ';
            app.CoeficientefriccionEditField_3Label = uilabel(app.Proyectil3Tab);
            app.CoeficientefriccionEditField_3Label.Text = 'Coeficiente friccion';
            app.CoeficientefriccionEditField3 = uieditfield(app.Proyectil3Tab, 'numeric');
            app.CoeficientefriccionEditField3.Value = 0.75;
            app.VelocidadinicialEditField_3Label = uilabel(app.Proyectil3Tab);
            app.VelocidadinicialEditField_3Label.Text = 'Velocidad inicial';
            app.VelocidadinicialEditField3 = uieditfield(app.Proyectil3Tab, 'numeric');
            app.VelocidadinicialEditField3.ValueDisplayFormat = '%.3f';
            app.VelocidadinicialEditField3.Value = 127.5;
            app.AnguloEditField_3Label = uilabel(app.Proyectil3Tab);
            app.AnguloEditField_3Label.Text = 'Angulo ';
            app.AnguloEditField3 = uieditfield(app.Proyectil3Tab, 'numeric');
            app.AnguloEditField3.ValueDisplayFormat = '%.3f';
            app.AnguloEditField3.Value = 83;
            app.MasaproyectilEditField_3Label = uilabel(app.Proyectil3Tab);
            app.MasaproyectilEditField_3Label.Text = 'Masa proyectil';
            app.MasaproyectilEditField3 = uieditfield(app.Proyectil3Tab, 'numeric');
            app.MasaproyectilEditField3.ValueDisplayFormat = '%.3f';
            app.MasaproyectilEditField3.Value = 150;
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.AlturaMaximaLabel = uilabel(app.ResultadosTab);
            app.AlturaMaximaLabel.Text = 'Altura Maxima';
            app.VelocidadImpactoLabel = uilabel(app.ResultadosTab);
            app.VelocidadImpactoLabel.Text = 'Velocidad Impacto';
            app.PuntodeimpactoxLabel = uilabel(app.ResultadosTab);
            app.PuntodeimpactoxLabel.Text = 'Punto de impacto (x)';
            app.ResultadosLabel = uilabel(app.ResultadosTab);
            app.ResultadosLabel.Text = 'Resultados';
            app.AlturaMaxima = uieditfield(app.ResultadosTab, 'numeric');
            app.VelocidadImpacto = uieditfield(app.ResultadosTab, 'numeric');
            app.mLabel_5 = uilabel(app.ResultadosTab);
            app.mLabel_5.Text = 'm';
            app.msLabel_4 = uilabel(app.ResultadosTab);
            app.msLabel_4.Text = 'm/s';
            app.mLabel_6 = uilabel(app.ResultadosTab);
            app.mLabel_6.Text = 'm';
            app.Impacto = uieditfield(app.ResultadosTab, 'numeric');
            app.AlturaMaxima_2 = uieditfield(app.ResultadosTab, 'numeric');
            app.VelocidadImpacto_2 = uieditfield(app.ResultadosTab, 'numeric');
            app.Impacto_2 = uieditfield(app.ResultadosTab, 'numeric');
            app.AlturaMaxima_3 = uieditfield(app.ResultadosTab, 'numeric');
            app.VelocidadImpacto_3 = uieditfield(app.ResultadosTab, 'numeric');
            app.Impacto_3 = uieditfield(app.ResultadosTab, 'numeric');
            app.Proyectil1Label = uilabel(app.ResultadosTab);
            app.Proyectil1Label.Text = 'Proyectil 1';
            app.Proyectil2Label = uilabel(app.ResultadosTab);
            app.Proyectil2Label.Text = 'Proyectil 2';
            app.Proyectil3Label = uilabel(app.ResultadosTab);
            app.Proyectil3Label.Text = 'Proyectil 3';
            % Show the figure after all components are created
            app.Simulador.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoVolcan2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Simulador)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Simulador)
        end
    end
end