classdef Volcancopia2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VelocidadInicialEditFieldLabel  matlab.ui.control.Label
        VelocidadInicialEditField       matlab.ui.control.NumericEditField
        AnguloEditFieldLabel            matlab.ui.control.Label
        AnguloEditField                 matlab.ui.control.NumericEditField
        AlturaH0EditFieldLabel          matlab.ui.control.Label
        AlturaH0EditField               matlab.ui.control.NumericEditField
        MasaEditFieldLabel              matlab.ui.control.Label
        MasaEditField                   matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            g = 9.8;
        end

        % Value changed function: VelocidadInicialEditField
        function VelocidadInicialEditFieldValueChanged(app, event)
            
            vinicial = 4000/app.MasaEditField.Value;
            altura = app.AlturaH0EditField.Value;
            angulo = app.AnguloEditField.Value;
            teta = angulo*3.1416/180;
            g = 9.8;
           
            %t = 2*Vo*sin(teta)/g;
            %Xmax = Vo*cos(teta)*t;
            t = ( (vinicial*sin(teta))+((vinicial*sin(teta))^2+2*altura*g)^0.5)/g;
            t1 = [0:0.05:t];
            Xmax = vinicial*cos(teta)*t1;
            xmax = vinicial*cos(teta)*t;
            ymax = altura + ((vinicial*sin(teta))^2)/(2*g);
            y = altura + (vinicial*sin(teta)).*t1-(0.5*g).*(t1.^2);
            y2 = altura + (tan(teta).*Xmax)-(0.5*g/(vinicial*cos(teta))^2).*(Xmax.^2);
            plot(app.UIAxes,Xmax,y2,'*',"Color",[1 1 0]);
        end

        % Value changed function: AnguloEditField
        function AnguloEditFieldValueChanged(app, event)
            
            vinicial = 4000/app.MasaEditField.Value;
            altura = app.AlturaH0EditField.Value;
            angulo = app.AnguloEditField.Value;
            teta = angulo*3.1416/180;
            g = 9.8;
           
            %t = 2*Vo*sin(teta)/g;
            %Xmax = Vo*cos(teta)*t;
            t = ( (vinicial*sin(teta))+(((vinicial*sin(teta))^2+2*altura*g)^0.5)/g);
            t1 = [0:0.05:t];
            Xmax = vinicial*cos(teta)*t1;
            xmax = vinicial*cos(teta)*t;
            ymax = altura + ((vinicial*sin(teta))^2)/(2*g);
            y = altura + (vinicial*sin(teta)).*t1-(0.5*g).*(t1.^2);
            y2 = altura + (tan(teta).*Xmax)-(0.5*g/(vinicial*cos(teta))^2).*(Xmax.^2);
            plot(app.UIAxes,Xmax,y2,'*',"Color",[1 1 0]);
        end

        % Value changed function: AlturaH0EditField
        function AlturaH0EditFieldValueChanged(app, event)
            
            vinicial = 4000/app.MasaEditField.Value;
            altura = app.AlturaH0EditField.Value;
            angulo = app.AnguloEditField.Value;
            teta = angulo*3.1416/180;
            g = 9.8;
           
            %t = 2*Vo*sin(teta)/g;
            %Xmax = Vo*cos(teta)*t;
            t = ( (vinicial*sin(teta))+((vinicial*sin(teta))^2+2*altura*g)^0.5)/g;
            t1 = [0:0.05:t];
            Xmax = vinicial*cos(teta)*t1;
            xmax = vinicial*cos(teta)*t;
            ymax = altura + ((vinicial*sin(teta))^2)/(2*g);
            y = altura + (vinicial*sin(teta)).*t1-(0.5*g).*(t1.^2);
            y2 = altura + (tan(teta).*Xmax)-(0.5*g/(vinicial*cos(teta))^2).*(Xmax.^2);
            plot(app.UIAxes,Xmax,y2,'*',"Color",[1 1 0]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VelocidadInicialEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadInicialEditFieldLabel.Text = 'Velocidad Inicial';
            app.VelocidadInicialEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocidadInicialEditField.ValueChangedFcn = createCallbackFcn(app, @VelocidadInicialEditFieldValueChanged, true);
            app.AnguloEditFieldLabel = uilabel(app.UIFigure);
            app.AnguloEditFieldLabel.Text = {'Angulo'; ''};
            app.AnguloEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnguloEditField.ValueChangedFcn = createCallbackFcn(app, @AnguloEditFieldValueChanged, true);
            app.AlturaH0EditFieldLabel = uilabel(app.UIFigure);
            app.AlturaH0EditFieldLabel.Text = 'Altura (H0)';
            app.AlturaH0EditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturaH0EditField.ValueChangedFcn = createCallbackFcn(app, @AlturaH0EditFieldValueChanged, true);
            app.MasaEditFieldLabel = uilabel(app.UIFigure);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'volcan 2.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volcancopia2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end