classdef App_Formula1_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pista_Formula_1       matlab.ui.Figure
        general               matlab.ui.control.UIAxes
        Switch                matlab.ui.control.Switch
        curva1                matlab.ui.control.UIAxes
        curva2                matlab.ui.control.UIAxes
        IniciarCarreraButton  matlab.ui.control.Button
        Switch_2              matlab.ui.control.Switch
        xLabel                matlab.ui.control.Label
        deltax                matlab.ui.control.NumericEditField
        ExportardatosButton   matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        PosicionTab           matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        InformacionPistaTab   matlab.ui.container.Tab
        Consolelog            matlab.ui.control.TextArea
        ConsolelogLabel       matlab.ui.control.Label
    end

    
    methods (Access = public)
        % Funcion para obtener longitud de curva
        function lg = getLongitudCurva(~, ecu, x1, x2)
            syms x
            ecudif = diff(ecu);
            ecul = sqrt(1 + (ecudif^2));
            lg = int(ecul, x,[x1,x2]);
        end
        
        function [y, f1, puntox, puntoy, m] = getTangente_f1(~, ecu, mini, n)
            syms x
            puntox = mini;
            ecudif = diff(ecu);
            m = subs(ecudif, x, puntox);
            puntoy = subs(ecu, x, puntox);
            y = m*x-m*puntox+puntoy;
            if(m>0) %Se evalua si la pendiente es positiva se establece una distancia sobre la tangente de -30
                f1 =-30 * n;
            else    %Si es negativa la distancia es de 30 sobre la tangente
                f1 =30 * n;
            end
        end
        
        function [x, y] = getPuntosGrada(~, puntox, puntoy, f1, m)
            x= puntox+f1*sqrt(1/(1+m^2)); %Se obtiene la X sobre la tangente que de distancia sea 20
            y= puntoy+m*f1*sqrt(1/(1+m^2)); %Se obtiene la Y sobre la tangente que de distancia sea 20
        end
        
        function [xgradai, xgradaf, ygradai, ygradaf] = getEsquinasGrada(~, x, y)
            xgradai=x-40; %Esquina izquierda de la barrera
            ygradai=y;  
            
            xgradaf=x+40; %Esquina derecha de la barrera
            ygradaf=y;
        end
        
        function [crit_p] = getPuntosCriticos(~, ecu)
            ecudif = diff(ecu);
            ecudif = simplify(ecudif);
            crit_p = solve(ecudif);
        end
        
        function [inflec_pt] = getPuntoInflexion(~, ecu)
            ecudif = diff(diff(ecu));  %Se obtiene la segunda derivada
            inflec_pt = solve(ecudif,'MaxDegree',3);  %Se resuelve la segunda derivada donde cambia la concavidad de la funcion de 3 grado
            idx = imag(double(inflec_pt)) == 0;     %Se resuelve donde es 0 en la funcion
            inflec_pt = inflec_pt(idx); 
        end
        
        function plotSinDerrape(~, px, delta, final, gen, curv1, curv2, table)
            for i=px:delta:final
                py = -0.000002249*i.^3+0.010770716*i.^2-13.061982421*i+1700.740063337;
                ph = plot(gen,i, py,'ob');
                ph1 = plot(curv1,i, py,'ob');
                ph2 = plot(curv2,i, py,'ob');
                pause(0.05);
                data=get(table, 'data'); % Get data as a string
                data(end+1,:)=[i py]; % Add new row of 0's as a cell type
                set(table, 'data', data);
            end     
            
        end
        
        function plotConDerrape(~, px, inter, final, m, delta, puntox, puntoy, gen, curv1, curv2, table)
            for i=px:delta:final
                if i < inter
                    py = -0.000002249*i.^3+0.010770716*i.^2-13.061982421*i+1700.740063337;
                else
                    py = m*i-m*puntox+puntoy;
                end
                ph = plot(gen,i, py,'ob');
                ph1 = plot(curv1,i, py,'ob');
                ph2 = plot(curv2,i, py,'ob');
                pause(0.05);
                data=get(table, 'data'); % Get data as a string
                data(end+1,:)=[i py]; % Add new row of 0's as a cell type
                set(table, 'data', data);
            end
        end
       
    end


    methods (Access = private)
        
        function writte(app,text)
            currString= app.Consolelog.Value;
            currString{end+1}=text;
            app.Consolelog.Value = currString;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Pista_Formula_1,"center");
        end

        % Button pushed function: IniciarCarreraButton
        function IniciarCarreraButtonPushed(app, event)
            syms a0 a1 a2 a3 x
            set(app.UITable,'Data',[])
            app.Consolelog.Value = '';
            value = app.Switch.Value;
            value2 = app.Switch_2.Value;
            
            hold(app.general,"on");
            hold(app.curva1,"on");
            hold(app.curva2,"on");
            
            delta =app.deltax.Value;            
            %Establecer escalas curvas
            xlim(app.curva1, [750 880]);
            ylim(app.curva1,[-3040 -2990]);
            
            xlim(app.curva2, [2320 2440])
            ylim(app.curva2,[1270 1330]);
            
            %%Obtener funcion
            x1=100;     %Punto inicial
            y1=500;
            
            x2=2800;    %Punto Final
            y2=200;     
            
            eq1= a3*x1^3+a2*x1^2+a1*x1+a0==y1;
            eq2= a3*x2^3+a2*x2^2+a1*x2+a0==y2;
            
            %Editables
            x3=775;     %Punto 1
            y3=-3000;
            
            x4=2125;    %Punto 2
            y4=1000;
            
            eq3= a3*x3^3+a2*x3^2+a1*x3+a0==y3;
            eq4= a3*x4^3+a2*x4^2+a1*x4+a0==y4;
            
            sol=solve([eq1,eq2,eq3,eq4],[a0,a1,a2,a3]);%%Solucion lineal de la ecuacion
            
            v1=double(sol.a0); %Se asignan los valores de cada coeficiente
            v2=double(sol.a1);
            v3=double(sol.a2);
            v4=double(sol.a3);
            
            fprintf("y=%.9fx^3+%.9fx^2+%.9fx+%.9f\n",v4,v3,v2,v1); %Se imprime la funcion
            f1 = sprintf('y=%.9fx^3+%.9fx^2+%.9fx+%.9f',v4,v3,v2,v1)
            writte(app,f1);
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %%Longitud de curvatura
            fx=v4*x^3+v3*x^2+v2*x+v1;  %Funcion general 
            lg = getLongitudCurva(app, fx, x1, x2); % Longitud de curva
            fprintf("Longitud de curva: %0.4f\n",lg);   %Se imprime la longitud
            f1 = sprintf('Longitud de curva: %0.4f',lg)
            writte(app,f1);
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Obtener minimos del radio de curvatura
            
            mini = 797;
            min = 93.687470156344;
            mini2 = 2354;
            min2 = 99.967353;
            
            fprintf("El valor minimo del radio %i de curvatura es de: %.12f\n",mini, min);      %Primer punto minimo de la funcion
            f1 = sprintf('El valor minimo del radio %i de curvatura es de: %.12f',mini, min);
            writte(app,f1);
            fprintf("El valor minimo del radio %i de curvatura es de: %.12f\n",mini2, min2);    %Segundo punto minimo de la funcion
            f1 = sprintf('El valor minimo del radio %i de curvatura es de: %.12f',mini2, min2);
            writte(app,f1);
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %%Tangente y punto medio de la grada_1
            
            [y2t, f1, puntox, puntoy, m] = getTangente_f1(app, fx, mini, 1);
            [x2f, y2f] = getPuntosGrada(app, puntox, puntoy, f1, m);
            
            %%Tangente y punto medio de la grada_2
            [y2t_2, f1_2, puntox_2, puntoy_2, m_2] = getTangente_f1(app, fx, mini2, -1);
            [x2f_2, y2f_2] = getPuntosGrada(app, puntox_2, puntoy_2, f1_2, m_2);
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %obtener puntos de la 1er barrera
            [xgrada1_i, xgrada1_f, ygrada1_i, ygrada1_f] = getEsquinasGrada(app, x2f, y2f);       
            fprintf("Coordenadas barrera1: (%.9f,%.9f),(%.9f,%.9f)\n",xgrada1_i,ygrada1_i,xgrada1_f,ygrada1_f);
            f1 = sprintf('Coordenadas barrera1: (%.9f,%.9f),(%.9f,%.9f)',xgrada1_i,ygrada1_i,xgrada1_f,ygrada1_f);
            writte(app,f1);
            
            %obtener puntos de la 2da barrera
            [xgrada2_i, xgrada2_f, ygrada2_i, ygrada2_f] = getEsquinasGrada(app, x2f_2, y2f_2);            
            fprintf("Coordenadas barrera2: (%.9f,%.9f),(%.9f,%.9f)\n",xgrada2_i,ygrada2_i,xgrada2_f,ygrada2_f); %Se imprimen las coordenadas
            f1 = sprintf('Coordenadas barrera2: (%.9f,%.9f),(%.9f,%.9f)',xgrada2_i,ygrada2_i,xgrada2_f,ygrada2_f);
            writte(app,f1);
           
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Puntos relevantes de la funcion
            
            %Puntos criticos
            [crit_p] = getPuntosCriticos(app, fx);  
            fprintf("Puntos Criticos: ");
            disp(crit_p);
            disp(class(crit_p));
            writte(app,sprintf('Puntos Criticos: %s', crit_p));
            
            %Puntos de inflexion
            [inflec_pt] = getPuntoInflexion(app, fx);
            fprintf("Puntos Inflexion: ");
            disp(inflec_pt);
            disp(class(inflec_pt));

            writte(app,sprintf('Punto inflexion:%s', inflec_pt));
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Plot graph
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Graficar funcion general
            
            %Graficar gradas
                hold(app.general,"off");
                hold(app.curva1,"off");
                hold(app.curva2,"off");
                %Punto inicial
                plot(app.general,x1,y1,'or');
                hold(app.general,"on");
                plot(app.curva1,x1,y1,'or');
                hold(app.curva1,"on");
                plot(app.curva2,x1,y1,'or');
                hold(app.curva2,"on");
                %Punto final
                plot(app.general,x2,y2,'ob');
                hold(app.general,"on");
                plot(app.curva1,x2,y2,'ob');
                hold(app.curva1,"on");
                plot(app.curva2,x2,y2,'ob');
                hold(app.curva2,"on");
                %Funcion general
                fplot(app.general,fx,[x1,x2],'color',[0 0 0]);
                hold(app.general,"on");
                fplot(app.curva1,fx,[x1,x2],'color',[0 0 0]);
                hold(app.curva1,"on");
                fplot(app.curva2,fx,[x1,x2],'color',[0 0 0]);
                hold(app.curva2,"on");
                
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Puntos criticos e inflexion
            
                %Puntos criticos
                plot(app.general,double(crit_p), double(subs(fx,crit_p)),'rx'); %Se grafican todos los puntos criticos/max/min de la funcion con una x en color rojo
                %%text(app.general,double(crit_p), double(subs(fx,crit_p)),'Minimo/Maximo Local');    %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.general,"on");
                plot(app.curva1,double(crit_p), double(subs(fx,crit_p)),'rx'); %Se grafican todos los puntos criticos/max/min de la funcion con una x en color rojo
                %%text(app.curva1,double(crit_p), double(subs(fx,crit_p)),'Minimo/Maximo Local');    %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva1,"on");
                plot(app.curva2,double(crit_p), double(subs(fx,crit_p)),'rx'); %Se grafican todos los puntos criticos/max/min de la funcion con una x en color rojo
                %%text(app.curva2,double(crit_p), double(subs(fx,crit_p)),'Minimo/Maximo Local');    %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva2,"on");
            
                %Punto inflexion
                plot(app.general,double(inflec_pt), double(subs(fx,inflec_pt)),'bx');   %Se grafican los puntos de inflexion con una x en color azul
                %%text(app.general,double(inflec_pt), double(subs(fx,inflec_pt)),'Punto inflexion')   %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.general,"on");
                plot(app.curva1,double(inflec_pt), double(subs(fx,inflec_pt)),'bx');   %Se grafican los puntos de inflexion con una x en color azul
                %text(app.curva1,double(inflec_pt), double(subs(fx,inflec_pt)),'Punto inflexion')   %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva1,"on");
                plot(app.curva2,double(inflec_pt), double(subs(fx,inflec_pt)),'bx');   %Se grafican los puntos de inflexion con una x en color azul
                %text(app.curva2,double(inflec_pt), double(subs(fx,inflec_pt)),'Punto inflexion')   %Se agrega %texto sobre las coordenadas de los puntos
                hold(app.curva2,"on");
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Tangentes
            
                %Tangente punto 1 minimo del radio
                fplot(app.general,y2t,[x1 x2], '--','color',[.32 .133 .9]); %Se grafica la funcion tangente del punto 1 minimo del radio 
                hold(app.general,"on");
                fplot(app.curva1,y2t,[x1 x2], '--','color',[.32 .133 .9]); %Se grafica la funcion tangente del punto 1 minimo del radio 
                hold(app.curva1,"on");
                fplot(app.curva2,y2t,[x1 x2], '--','color',[.32 .133 .9]); %Se grafica la funcion tangente del punto 1 minimo del radio 
                hold(app.curva2,"on");
                
                %Tangente punto 2 minimo del radio
                fplot(app.general,y2t_2,[x1 x2], '--','color',[.32 .133 .9]);   %Se grafica la funcion tangente del punto 2 minimo del radio
                hold(app.general,"on");
                fplot(app.curva1,y2t_2,[x1 x2], '--','color',[.32 .133 .9]);   %Se grafica la funcion tangente del punto 2 minimo del radio
                hold(app.curva1,"on");
                fplot(app.curva2,y2t_2,[x1 x2], '--','color',[.32 .133 .9]);   %Se grafica la funcion tangente del punto 2 minimo del radio
                hold(app.curva2,"on");
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
                %Puntos con radio menor a 100
            
                %Punto 1 radio<=100
                plot(app.general,puntox, puntoy, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.general,"on");
                plot(app.curva1,puntox, puntoy, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva1,"on");
                plot(app.curva2,puntox, puntoy, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva2,"on");
            
                %Punto 2 radio<=100
                plot(app.general,puntox_2, puntoy_2, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.general,"on");
                plot(app.curva1,puntox_2, puntoy_2, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva1,"on");
                plot(app.curva2,puntox_2, puntoy_2, 'or'); %Se grafica el punto 1 minimo del radio
                hold(app.curva2,"on");
            
                %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
                %Punto Central Gradas 1
                plot(app.general,x2f, y2f, 'o', 'color',[.79 .5 .181]); %Se grafica el punto central de la primer grada/barrera
                hold(app.general,"on");
                plot(app.curva1,x2f, y2f, 'o', 'color',[.79 .5 .181]); %Se grafica el punto central de la primer grada/barrera
                hold(app.curva1,"on");
                plot(app.curva2,x2f, y2f, 'o', 'color',[.79 .5 .181]); %Se grafica el punto central de la primer grada/barrera
                hold(app.curva2,"on");
            
                %Punto Central Gradas 2
                plot(app.general,x2f_2, y2f_2, 'o','color',[.79 .5 .181]);  %Se grafica el punto central de la segunda grada/barrera
                hold(app.general,"on");
                plot(app.curva1,x2f_2, y2f_2, 'o','color',[.79 .5 .181]);  %Se grafica el punto central de la segunda grada/barrera
                hold(app.curva1,"on");
                plot(app.curva2,x2f_2, y2f_2, 'o','color',[.79 .5 .181]);  %Se grafica el punto central de la segunda grada/barrera
                hold(app.curva2,"on");
            
                %%Grada 1
                plot(app.general,xgrada1_i,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.general,"on");
                plot(app.general,xgrada1_f,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.general,[xgrada1_i, xgrada1_f], [ygrada1_f, ygrada1_f],'color',[0 0 0]);   %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1
                
                plot(app.curva1,xgrada1_i,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva1,"on");
                plot(app.curva1,xgrada1_f,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva1,[xgrada1_i, xgrada1_f], [ygrada1_f, ygrada1_f],'color',[0 0 0]);   %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1
                
                plot(app.curva2,xgrada1_i,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva2,"on");
                plot(app.curva2,xgrada1_f,ygrada1_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva2,[xgrada1_i, xgrada1_f], [ygrada1_f, ygrada1_f],'color',[0 0 0]);   %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1
                
                gx1_i=[xgrada1_i xgrada1_i];   
                gy1_i=[ygrada1_f ygrada1_f-10];
                plot(app.general,gx1_i,gy1_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva1,gx1_i,gy1_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva2,gx1_i,gy1_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
            
                hold(app.general,"on");
                plot(app.general,xgrada1_i,ygrada1_f-10,'o','color',[0 0 0]);
                hold(app.general,"on");
                plot(app.general,xgrada1_f,ygrada1_f-10,'o','color',[0 0 0]);
                plot(app.general,[xgrada1_i, xgrada1_f], [ygrada1_f-10, ygrada1_f-10],'color',[0 0 0]); %Se grafica una linea horizontal entre el izquierdo 2 y derecho 2
                
                hold(app.curva1,"on");
                plot(app.curva1,xgrada1_i,ygrada1_f-10,'o','color',[0 0 0]);
                hold(app.curva1,"on");
                plot(app.curva1,xgrada1_f,ygrada1_f-10,'o','color',[0 0 0]);
                plot(app.curva1,[xgrada1_i, xgrada1_f], [ygrada1_f-10, ygrada1_f-10],'color',[0 0 0]); %Se grafica una linea horizontal entre el izquierdo 2 y derecho 2 
                
                hold(app.curva2,"on");
                plot(app.curva2,xgrada1_i,ygrada1_f-10,'o','color',[0 0 0]);
                hold(app.curva2,"on");
                plot(app.curva2,xgrada1_f,ygrada1_f-10,'o','color',[0 0 0]);
                plot(app.curva2,[xgrada1_i, xgrada1_f], [ygrada1_f-10, ygrada1_f-10],'color',[0 0 0]); %Se grafica una linea horizontal entre el izquierdo 2 y derecho 2 
                
                gx1_f=[xgrada1_f xgrada1_f];
                gy1_f=[ygrada1_f ygrada1_f-10];
                plot(app.general,gx1_f,gy1_f,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva1,gx1_f,gy1_f,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva2,gx1_f,gy1_f,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
            
                hold(app.general,"on");
                hold(app.curva1,"on");
                hold(app.curva2,"on");
            
                %Gradas 2
                plot(app.general,xgrada2_i,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.general,"on");
                plot(app.curva1,xgrada2_i,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva1,"on");
                plot(app.curva2,xgrada2_i,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la primer grada/barrera
                hold(app.curva2,"on");
                plot(app.general,xgrada2_f,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.general,[xgrada2_i, xgrada2_f], [ygrada2_f, ygrada2_f],'color',[0 0 0]) %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1 
                plot(app.curva1,xgrada2_f,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva1,[xgrada2_i, xgrada2_f], [ygrada2_f, ygrada2_f],'color',[0 0 0]) %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1 
                plot(app.curva2,xgrada2_f,ygrada2_f,'o','color',[0 0 0]);  %Se grafica el punto izquierdo de la segunda grada/barrera
                plot(app.curva2,[xgrada2_i, xgrada2_f], [ygrada2_f, ygrada2_f],'color',[0 0 0]) %Se grafica una linea horizontal entre el izquierdo 1 y derecho 1 
                
                gx2_i=[xgrada2_i xgrada2_i];
                gy2_i=[ygrada2_f ygrada2_f+10];
                plot(app.general,gx2_i,gy2_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva1,gx2_i,gy2_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
                plot(app.curva2,gx2_i,gy2_i,'color',[0 0 0]);  %Se grafica una linea vertical entre el punto izquierdo 1 e izquierdo 2
            
                hold(app.general,"on");
                plot(app.general,xgrada2_i,ygrada2_f+10,'o','color',[0 0 0]);
                hold(app.general,"on");
                plot(app.general,xgrada2_f,ygrada2_f+10,'o','color',[0 0 0]);
                plot(app.general,[xgrada2_i, xgrada2_f], [ygrada2_f+10, ygrada2_f+10],'color',[0 0 0]) %Se grafica una linea horizontal entre el derecho 1 e izquierdo 2 
                hold(app.curva1,"on");
                plot(app.curva1,xgrada2_i,ygrada2_f+10,'o','color',[0 0 0]);
                hold(app.curva1,"on");
                plot(app.curva1,xgrada2_f,ygrada2_f+10,'o','color',[0 0 0]);
                plot(app.curva1,[xgrada2_i, xgrada2_f], [ygrada2_f+10, ygrada2_f+10],'color',[0 0 0]) %Se grafica una linea horizontal entre el derecho 1 e izquierdo 2 
                hold(app.curva2,"on");
                plot(app.curva2,xgrada2_i,ygrada2_f+10,'o','color',[0 0 0]);
                hold(app.curva2,"on");
                plot(app.curva2,xgrada2_f,ygrada2_f+10,'o','color',[0 0 0]);
                plot(app.curva2,[xgrada2_i, xgrada2_f], [ygrada2_f+10, ygrada2_f+10],'color',[0 0 0]) %Se grafica una linea horizontal entre el derecho 1 e izquierdo 2 
                
                gx2_f=[xgrada2_f xgrada2_f];
                gy2_f=[ygrada2_f ygrada2_f+10];
                plot(app.general,gx2_f,gy2_f,'color',[0 0 0]); %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva1,gx2_f,gy2_f,'color',[0 0 0]); %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
                plot(app.curva2,gx2_f,gy2_f,'color',[0 0 0]); %Se grafica una linea vertical entre el punto derecho 1 y derecho 2
            
            %-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*-*
            
            %Graficar particula      
            general = app.general;
            curva1 = app.curva1;
            curva2 = app.curva2;
            table = app.UITable;
            px = 100;
            if(value == "Sin Derrape")
                final = 2800;
                plotSinDerrape(app, px, delta, final, general, curva1, curva2, table);           
            else
                if(value2 == "Curva 1")
                    inter = 797;
                    final = 816;
                    plotConDerrape(app, px, inter, final, m, delta, puntox, puntoy, general, curva1, curva2, table);                    
                end
                if(value2 == "Curva 2")
                    inter = 2354;
                    final = 2373;
                    %[y2t_2, f1_2, puntox_2, puntoy_2, m_2] = getTangente_f1(app, fx, mini2, -1);
                    %[x2f_2, y2f_2] = getPuntosGrada(app, puntox_2, puntoy_2, f1_2, m_2);
                    
                    plotConDerrape(app, px, inter, final, m_2, delta, puntox_2, puntoy_2, general, curva1, curva2, table);     
                end
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value2 = app.Switch_2.Value;
        end

        % Button pushed function: ExportardatosButton
        function ExportardatosButtonPushed(app, event)
            data = get(app.UITable, 'data');
            columns = get(app.UITable, 'columnname');
            columns = reshape(columns, [1,2]);
            filename = 'Datos_F1.xls';
            xlswrite(filename, data, 1, 'A2');
            xlswrite(filename, columns, 1, 'A1:B1');
            command = 'start excel Datos_F1.xls';
            command2 = 'open Datos_F1.xls';
            system(command);
            system(command2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Pista_Formula_1 = uifigure('Visible', 'off');
            app.Pista_Formula_1.Name = 'UI Figure';
            app.general = uiaxes(app.Pista_Formula_1);
            title(app.general, 'Vista General')
            xlabel(app.general, 'X')
            ylabel(app.general, 'Y')
            app.Switch = uiswitch(app.Pista_Formula_1, 'slider');
            app.Switch.Items = {'Sin Derrape', 'Derrape'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Sin Derrape';
            app.curva1 = uiaxes(app.Pista_Formula_1);
            title(app.curva1, 'Curva 1')
            xlabel(app.curva1, 'X')
            ylabel(app.curva1, 'Y')
            app.curva2 = uiaxes(app.Pista_Formula_1);
            title(app.curva2, 'Curva 2')
            xlabel(app.curva2, 'X')
            ylabel(app.curva2, 'Y')
            app.IniciarCarreraButton = uibutton(app.Pista_Formula_1, 'push');
            app.IniciarCarreraButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarCarreraButtonPushed, true);
            app.IniciarCarreraButton.Text = 'Iniciar Carrera';
            app.Switch_2 = uiswitch(app.Pista_Formula_1, 'slider');
            app.Switch_2.Items = {'Curva 1', 'Curva 2'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Curva 1';
            app.xLabel = uilabel(app.Pista_Formula_1);
            app.xLabel.Text = 'Δx';
            app.deltax = uieditfield(app.Pista_Formula_1, 'numeric');
            app.deltax.Value = 5;
            app.ExportardatosButton = uibutton(app.Pista_Formula_1, 'push');
            app.ExportardatosButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosButtonPushed, true);
            app.ExportardatosButton.Text = 'Exportar datos';
            app.TabGroup = uitabgroup(app.Pista_Formula_1);
            app.PosicionTab = uitab(app.TabGroup);
            app.PosicionTab.Title = 'Posicion';
            app.UITable = uitable(app.PosicionTab);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.InformacionPistaTab = uitab(app.TabGroup);
            app.InformacionPistaTab.Title = 'Informacion Pista';
            app.Consolelog = uitextarea(app.InformacionPistaTab);
            app.ConsolelogLabel = uilabel(app.InformacionPistaTab);
            app.ConsolelogLabel.Text = 'Console log';
            % Show the figure after all components are created
            app.Pista_Formula_1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Formula1_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pista_Formula_1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pista_Formula_1)
        end
    end
end