classdef PolinomiosR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Raicesdepolinomios              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        MtododeraicesdepolinomiosLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GraficarButton                  matlab.ui.control.Button
        SeleccindegrficaButtonGroup     matlab.ui.container.ButtonGroup
        Grafica1Button                  matlab.ui.control.RadioButton
        Grafica2Button                  matlab.ui.control.RadioButton
        Grafica3Button                  matlab.ui.control.RadioButton
        Grafica4Button                  matlab.ui.control.RadioButton
        VectordepolinomioEditFieldLabel  matlab.ui.control.Label
        VectordepolinomioEditField      matlab.ui.control.EditField
        ValoresderaizEditFieldLabel     matlab.ui.control.Label
        ValoresderaizEditField          matlab.ui.control.EditField
        ObtenerraicesButton             matlab.ui.control.Button
        DatosdelpoliniomioLabel         matlab.ui.control.Label
        PolinomioEditFieldLabel         matlab.ui.control.Label
        PolinomioEditField              matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        LimpiarButton                   matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Raicesdepolinomios,'center');
        end

        % Button pushed function: ObtenerraicesButton
        function ObtenerraicesButtonPushed(app, event)
            if(app.VectordepolinomioEditField.Value ~="")
                a = textscan(app.VectordepolinomioEditField.Value,'%f','Delimiter',',');
                a = a{1};
                disp(a)
                r = Raicesp(a)
                app.ValoresderaizEditField.Value = num2str(r);
                hold(app.UIAxes,"on")
                if(app.Grafica1Button.Value == 1)
                    p = @(x) x.^3-6.*x.^2+11.*x-6;
                    a=p(r);
                    plot(app.UIAxes,r,a,'*k','LineWidth',8);
                elseif(app.Grafica2Button.Value== 1)
                    p=@(x)x.^3-x.^2-4.*x+4;
                    a=p(r);
                    plot(app.UIAxes,r,a,'*k','LineWidth',8);
                elseif(app.Grafica3Button.Value== 1)
                    p=@(x)-x.^4+10.*x.^2-9;
                    a=p(r);
                    plot(app.UIAxes,r,a,'*k','LineWidth',8);
                elseif(app.Grafica4Button.Value== 1)
                    p=@(x)2.*x.^3+x.^2-13.*x+6;
                    a=p(r);
                    plot(app.UIAxes,r,a,'*k','LineWidth',8);
                end
                
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            app.UIAxes.cla;
            if(app.Grafica1Button.Value == 1)
                p = @(x) x.^3-6.*x.^2+11.*x-6;
                x = -1:0.1:5;
                plot(app.UIAxes,x,p(x),'Color','r','LineWidth',4);
                title(app.UIAxes,'y=x^3-6x^2+11x-6');
                app.PolinomioEditField.Value ="x^3-6x^2+11x-6";   
            elseif(app.Grafica2Button.Value== 1)
                p=@(x)x.^3-x.^2-4.*x+4;
                x = -5:0.1:5;
                plot(app.UIAxes,x,p(x),'Color','g','LineWidth',4);
                title(app.UIAxes,'y=x^3-x^2-4x+4');
                app.PolinomioEditField.Value ="x^3-x^2-4x+4";
            elseif(app.Grafica3Button.Value== 1)
                p=@(x)-x.^4+10.*x.^2-9;
                x = -4:0.1:4;
                plot(app.UIAxes,x,p(x),'Color','b','LineWidth',4);
                title(app.UIAxes,'y=-x^4+10x^2-9');
                app.PolinomioEditField.Value ="-x^4+10x^2-9";
            elseif(app.Grafica4Button.Value== 1)
                p=@(x)2.*x.^3+x.^2-13.*x+6;
                x = -4:0.1:4;
                plot(app.UIAxes,x,p(x),'Color','c','LineWidth',4);
                title(app.UIAxes,'y=2x^3+x^2-13x+6');
                app.PolinomioEditField.Value ="2x^3+x^2-13x+6";
            else
                app.PolinomioEditField.Value ="Seleccionar del conjunto de gráficas";
            end
            
            grid(app.UIAxes,'on')
            
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.UIAxes.cla;
            app.ValoresderaizEditField.Value ="";
            app.VectordepolinomioEditField.Value ="";
            app.PolinomioEditField.Value ="";
            title(app.UIAxes,'');
            
            app.Grafica1Button.Value= 1;
            app.Grafica2Button.Value= 0;
            app.Grafica3Button.Value= 0;
            app.Grafica4Button.Value= 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Raicesdepolinomios.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {526, 526};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {665, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Raicesdepolinomios = uifigure('Visible', 'off');
            app.Raicesdepolinomios.Name = 'Raices de polinomios';
            app.Raicesdepolinomios.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Raicesdepolinomios);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MtododeraicesdepolinomiosLabel = uilabel(app.LeftPanel);
            app.MtododeraicesdepolinomiosLabel.Text = 'Método de raices de polinomios';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraficarButton = uibutton(app.RightPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.SeleccindegrficaButtonGroup = uibuttongroup(app.RightPanel);
            app.SeleccindegrficaButtonGroup.Title = 'Selección de gráfica';
            app.Grafica1Button = uiradiobutton(app.SeleccindegrficaButtonGroup);
            app.Grafica1Button.Text = 'Gráfica 1';
            app.Grafica1Button.Value = true;
            app.Grafica2Button = uiradiobutton(app.SeleccindegrficaButtonGroup);
            app.Grafica2Button.Text = 'Gráfica 2';
            app.Grafica3Button = uiradiobutton(app.SeleccindegrficaButtonGroup);
            app.Grafica3Button.Text = 'Gráfica 3';
            app.Grafica4Button = uiradiobutton(app.SeleccindegrficaButtonGroup);
            app.Grafica4Button.Text = 'Gráfica 4';
            app.VectordepolinomioEditFieldLabel = uilabel(app.RightPanel);
            app.VectordepolinomioEditFieldLabel.Text = 'Vector de polinomio';
            app.VectordepolinomioEditField = uieditfield(app.RightPanel, 'text');
            app.ValoresderaizEditFieldLabel = uilabel(app.RightPanel);
            app.ValoresderaizEditFieldLabel.Text = 'Valores de raiz';
            app.ValoresderaizEditField = uieditfield(app.RightPanel, 'text');
            app.ValoresderaizEditField.Editable = 'off';
            app.ObtenerraicesButton = uibutton(app.RightPanel, 'push');
            app.ObtenerraicesButton.ButtonPushedFcn = createCallbackFcn(app, @ObtenerraicesButtonPushed, true);
            app.ObtenerraicesButton.Text = 'Obtener raices';
            app.DatosdelpoliniomioLabel = uilabel(app.RightPanel);
            app.DatosdelpoliniomioLabel.Text = 'Datos del poliniomio';
            app.PolinomioEditFieldLabel = uilabel(app.RightPanel);
            app.PolinomioEditFieldLabel.Text = 'Polinomio';
            app.PolinomioEditField = uieditfield(app.RightPanel, 'text');
            app.PolinomioEditField.Editable = 'off';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '___________________________';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '___________________________';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '___________________________';
            app.LimpiarButton = uibutton(app.RightPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.Raicesdepolinomios.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolinomiosR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Raicesdepolinomios)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Raicesdepolinomios)
        end
    end
end