classdef Simulador_de_Campo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Simulacion                    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        label_graph                   matlab.ui.control.Image
        RightPanel                    matlab.ui.container.Panel
        SimularButton                 matlab.ui.control.Button
        CargaEditField_2Label         matlab.ui.control.Label
        carga2                        matlab.ui.control.NumericEditField
        PosicinenelejeLabel           matlab.ui.control.Label
        pos2                          matlab.ui.control.NumericEditField
        CargaEditFieldLabel           matlab.ui.control.Label
        carga1                        matlab.ui.control.NumericEditField
        PosicinenelejeLabel_2         matlab.ui.control.Label
        pos1                          matlab.ui.control.NumericEditField
        TipodedistribucinSwitchLabel  matlab.ui.control.Label
        distribucion                  matlab.ui.control.Switch
        DistanciaLabel                matlab.ui.control.Label
        distancia                     matlab.ui.control.NumericEditField
        Distribucin2Label             matlab.ui.control.Label
        Distribucin1Label             matlab.ui.control.Label
        PosicionamientoLabel          matlab.ui.control.Label
        DistanciaLabel_2              matlab.ui.control.Label
        distancia2                    matlab.ui.control.NumericEditField
        DistanciaLabel_3              matlab.ui.control.Label
        distancia1                    matlab.ui.control.NumericEditField
        NmerodecargastotalesEditFieldLabel  matlab.ui.control.Label
        ncargas                       matlab.ui.control.NumericEditField
        SimuladordecampoparacargaslinealesLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Button                        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function vertical_array(app)
            %array 1 de cargas
            carga1 = app.carga1.Value;
            pos1 = app.pos1.Value-0.5;
            d1 = app.distancia1.Value;
            
            %array 2 de cargas
            carga2 = app.carga2.Value;
            pos2 = app.pos2.Value-0.5;
            d2 = app.distancia2.Value;
            
            ncargas = app.ncargas.Value;
            
            puntos = 185;
            
            if pos1>pos2
                app.distancia.Value =abs(pos1-pos2);
            elseif pos2>pos1
                app.distancia.Value = abs(pos2-pos1);
            else
                app.distancia.Value=0;
            end
            
            vcargas=ones(ncargas);
            for i=1:ncargas
                if i<=ncargas/2
                    vcargas(i)=vcargas(i)*carga1;
                else
                    vcargas(i)=vcargas(i)*carga2;
                end
            end
            
            %definir posicion de las cargas
            xcargas=zeros(ncargas);
            ycargas=zeros(ncargas);
            
            cargas=floor(ncargas/2)-1;
            cargas = floor(cargas/2);
            
            counter = 1;
            for i=d1:d1:(cargas*d1)
                xcargas(counter)=pos1+0.5;
                ycargas(counter)=i;
                counter= counter+1;
            end
            for i=(-cargas*d1):d1:0
                xcargas(counter)=pos1+0.5;
                ycargas(counter)=i;
                counter= counter+1;
            end
            
            for i=d2:d2:(cargas*d2)
                xcargas(counter)=pos2+0.5;
                ycargas(counter)=i;
                counter= counter+1;
            end
            for i=(-cargas*d2):d2:0
                xcargas(counter)=pos2+0.5;
                ycargas(counter)=i;
                counter= counter+1;
            end
            
            disp("CARGAS")
            disp(xcargas)
            disp(ycargas)
            disp("CARGAS END")
            
            
            epsilon = 8.854e-12;%Escribir el valor de epsilon es un valor para hacer la ecuacion de campo electrico
            k = 1/(4 * pi * epsilon);
            epsilon = 8.854e-12;%Escribir el valor de epsilon
            %es un valor para hacer la ecuacion del campo electrico
            k = 1/(4 * pi * epsilon);
            
            %Dimensiones de la grafica
            minx= -100;
            maxx= 100;
            
            maxy = 100;
            miny= -100;
            
            %campo electrico
            campoex = zeros(puntos);
            campoey = zeros(puntos);
            
            %Crear 2 vectores
                 %minimo y maximo para numero de divisiones
            x =linspace(minx,maxx,puntos);%va de menos -1.5 a 1.5 dividido en npuntos
            y =linspace(miny,maxy,puntos);%estos son vectores
            
            %crear malla de puntos
            [xpuntosmalla,ypuntosmalla]=meshgrid(x,y);
            
            %crear un for que recorra las cargas para poder obtener el calculo de campo
            %electrico
            
            for i=1:ncargas
                dx = xpuntosmalla-xcargas(i);%obtiene distancias
                dy = ypuntosmalla-ycargas(i);%obtiene distancias
                
                r = sqrt(dx.^2+dy.^2);%distancias totales/ magnitud
                r3 = r.^3;%distnacias entre donde esta la carga y los elementos que rodean la carga
                
                %aqui es por componentes
                campoex=campoex+k.*vcargas(i).*dx./r3;
                campoey=campoey+k.*vcargas(i).*dy./r3;
                
            end
          
            campoe_total = sqrt(campoex.^2+campoey.^2);
            
            %graficar
            quiver(app.UIAxes,xpuntosmalla,ypuntosmalla,campoex./campoe_total,campoey./campoe_total);%se aplica normalizacion
            for i=1:length(xcargas)
                x = xcargas(i);
                y = ycargas(i);
            end
            
            %graph
            graph_array1(app,pos1,d1,carga1,ncargas);
            graph_array1(app,pos2,d2,carga2,ncargas);
            
            %añade axis
            axh = app.UIAxes; % use current axes
            color = 'k'; % black, or [0 0 0]
            linestyle = ':'; % dotted
            line(app.UIAxes,get(axh,'XLim'), [0 0], 'Color', color, 'LineStyle', linestyle);
            line(app.UIAxes,[0 0], get(axh,'YLim'), 'Color', color, 'LineStyle', linestyle);
            
            if(pos1<=0 && pos2>=0)
                axis(app.UIAxes,[((pos1*(-2)-5)) ((pos2*(2)+5)) (d1-5) (d2+5)]);
            end
        end
        
        function horizontal_array(app)
            %array 1 de cargas
            carga1 = app.carga1.Value;
            pos1 = app.pos1.Value-0.5;
            d1 = app.distancia1.Value;
            
            %array 2 de cargas
            carga2 = app.carga2.Value;
            pos2 = app.pos2.Value-0.5;
            d2 = app.distancia2.Value;
            
            ncargas = app.ncargas.Value;
            
            puntos = 185;
            
            if pos1>pos2
                app.distancia.Value =abs(pos1-pos2);
            elseif pos2>pos1
                app.distancia.Value = abs(pos2-pos1);
            else
                app.distancia.Value=0;
            end
            
            vcargas=ones(ncargas);
            for i=1:ncargas
                if i<=ncargas/2
                    vcargas(i)=vcargas(i)*carga1;
                else
                    vcargas(i)=vcargas(i)*carga2;
                end
            end
            
            %definir posicion de las cargas
            xcargas=zeros(ncargas);
            ycargas=zeros(ncargas);
            
            cargas=floor(ncargas/2)-1;
            cargas = floor(cargas/2);
            
            counter = 1;
            for i=d1:d1:(cargas*d1)
                xcargas(counter)=i;
                ycargas(counter)=pos1+0.5;
                counter= counter+1;
            end
            for i=(-cargas*d1):d1:0
                xcargas(counter)=i;
                ycargas(counter)=pos1+0.5;
                counter= counter+1;
            end
            
            for i=d2:d2:(cargas*d2)
                xcargas(counter)=i;
                ycargas(counter)=pos2+0.5;
                counter= counter+1;
            end
            for i=(-cargas*d2):d2:0
                xcargas(counter)=i;
                ycargas(counter)=pos2+0.5;
                counter= counter+1;
            end
            
            disp("CARGAS")
            disp(xcargas)
            disp(ycargas)
            disp("CARGAS END")
            
            
            epsilon = 8.854e-12;%Escribir el valor de epsilon es un valor para hacer la ecuacion de campo electrico
            k = 1/(4 * pi * epsilon);
            epsilon = 8.854e-12;%Escribir el valor de epsilon
            %es un valor para hacer la ecuacion del campo electrico
            k = 1/(4 * pi * epsilon);
            
            %Dimensiones de la grafica
            minx= -100;
            maxx= 100;
            
            maxy = 100;
            miny= -100;
            
            %campo electrico
            campoex = zeros(puntos);
            campoey = zeros(puntos);
            
            %Crear 2 vectores
                 %minimo y maximo para numero de divisiones
            x =linspace(minx,maxx,puntos);%va de menos -1.5 a 1.5 dividido en npuntos
            y =linspace(miny,maxy,puntos);%estos son vectores
            
            %crear malla de puntos
            [xpuntosmalla,ypuntosmalla]=meshgrid(x,y);
            
            %crear un for que recorra las cargas para poder obtener el calculo de campo
            %electrico
            
            for i=1:ncargas
                dx = xpuntosmalla-xcargas(i);%obtiene distancias
                dy = ypuntosmalla-ycargas(i);%obtiene distancias
                
                r = sqrt(dx.^2+dy.^2);%distancias totales/ magnitud
                r3 = r.^3;%distnacias entre donde esta la carga y los elementos que rodean la carga
                
                %aqui es por componentes
                campoex=campoex+k.*vcargas(i).*dx./r3;
                campoey=campoey+k.*vcargas(i).*dy./r3;
                
            end
          
            campoe_total = sqrt(campoex.^2+campoey.^2);
            
            %graficar
            quiver(app.UIAxes,xpuntosmalla,ypuntosmalla,campoex./campoe_total,campoey./campoe_total);%se aplica normalizacion
            for i=1:length(xcargas)
                x = xcargas(i);
                y = ycargas(i);
            end
            
            %graph
            graph_array2(app,pos1,d1,carga1,ncargas);
            graph_array2(app,pos2,d2,carga2,ncargas);
            
            %añade axis
            axh = app.UIAxes; % use current axes
            color = 'k'; % black, or [0 0 0]
            linestyle = ':'; % dotted
            line(app.UIAxes,get(axh,'XLim'), [0 0], 'Color', color, 'LineStyle', linestyle);
            line(app.UIAxes,[0 0], get(axh,'YLim'), 'Color', color, 'LineStyle', linestyle);
            
            if(pos1<=0 && pos2>=0)
                axis(app.UIAxes,[((pos1*(-2)-5)) ((pos2*(2)+5)) (d1-5) (d2+5)]);
            end
        end
        
        function graph_array1(app,pos,d,c,ncargas)
            
            cargas=floor(ncargas/2)-1;
            cargas = floor(cargas/2);
            
            if(c<0)
                color = 'b';
                chex = [0 0 1];
            elseif c>0
                color = 'r';
                chex = [1 0 0];
            else
                color = 'k';
                chex = [0 0 0];
            end


            for i=d:d:(cargas*d)
                rectangle(app.UIAxes,'Position',[pos i-0.5 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);

            end
            for i=(-cargas*d):d:0
                rectangle(app.UIAxes,'Position',[pos i-0.5 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);
            end
            
        end
        
        function graph_array2(app,pos,d,c,ncargas)
            
            cargas=floor(ncargas/2)-1;
            cargas = floor(cargas/2);
            
            if(c<0)
                color = 'b';
                chex = [0 0 1];
            elseif c>0
                color = 'r';
                chex = [1 0 0];
            else
                color = 'k';
                chex = [0 0 0];
            end

            for i=d:d:(cargas*d)
                rectangle(app.UIAxes,'Position',[i-0.5 pos 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);

            end
            for i=(-cargas*d):d:0
                rectangle(app.UIAxes,'Position',[i-0.5 pos 1 1.2],'Curvature',[1,1],'FaceColor',color,'EdgeColor',chex);
            end
            
        end
        
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Simulacion,'center');
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            if(app.distribucion.Value == "Vertical")
                vertical_array(app);
            else
                horizontal_array(app);
            end
            title(app.UIAxes,'Cargas lineales (alambres)');
            xlabel(app.UIAxes,'X (m)');
            ylabel(app.UIAxes,'Y (m)');
            app.label_graph.Visible = true;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            decodedValue = "	Modelación computacional de sistemas eléctricos Equipo: Ricardo Arriaga A01570553, Javier Banegas A00827812, Juan Carlos Garfias A01652138, Karla Olvera A01379097, Hernán Salinas A01570409 (ITESM, 2020)";
            
            fig = figure("Name",'Informacion');
            hPan = uipanel(fig,'Units','normalized');

            uicontrol(hPan, 'Style','text', 'HorizontalAlignment','center', ...
            'FontSize',10, 'Units','normalized', 'Position',[0.2 0.4 0.6 0.2], ...
            'String',decodedValue);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Simulacion.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {585, 585};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {629, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Simulacion = uifigure('Visible', 'off');
            app.Simulacion.Name = 'Simulador de campo';
            app.Simulacion.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Simulacion);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            app.label_graph = uiimage(app.LeftPanel);
            app.label_graph.Visible = 'off';
            app.label_graph.ImageSource = 'Label.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.SimularButton = uibutton(app.RightPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.CargaEditField_2Label = uilabel(app.RightPanel);
            app.CargaEditField_2Label.Text = 'Carga';
            app.carga2 = uieditfield(app.RightPanel, 'numeric');
            app.carga2.Value = -2;
            app.PosicinenelejeLabel = uilabel(app.RightPanel);
            app.PosicinenelejeLabel.Text = 'Posición en el eje';
            app.pos2 = uieditfield(app.RightPanel, 'numeric');
            app.pos2.Value = 5;
            app.CargaEditFieldLabel = uilabel(app.RightPanel);
            app.CargaEditFieldLabel.Text = 'Carga';
            app.carga1 = uieditfield(app.RightPanel, 'numeric');
            app.carga1.Value = 2;
            app.PosicinenelejeLabel_2 = uilabel(app.RightPanel);
            app.PosicinenelejeLabel_2.Text = 'Posición en el eje';
            app.pos1 = uieditfield(app.RightPanel, 'numeric');
            app.TipodedistribucinSwitchLabel = uilabel(app.RightPanel);
            app.TipodedistribucinSwitchLabel.Text = 'Tipo de distribución';
            app.distribucion = uiswitch(app.RightPanel, 'slider');
            app.distribucion.Items = {'Vertical', 'Horizontal'};
            app.distribucion.Value = 'Vertical';
            app.DistanciaLabel = uilabel(app.RightPanel);
            app.DistanciaLabel.Text = 'Distancia';
            app.distancia = uieditfield(app.RightPanel, 'numeric');
            app.distancia.Editable = 'off';
            app.Distribucin2Label = uilabel(app.RightPanel);
            app.Distribucin2Label.Text = 'Distribución 2';
            app.Distribucin1Label = uilabel(app.RightPanel);
            app.Distribucin1Label.Text = 'Distribución 1';
            app.PosicionamientoLabel = uilabel(app.RightPanel);
            app.PosicionamientoLabel.Text = 'Posicionamiento';
            app.DistanciaLabel_2 = uilabel(app.RightPanel);
            app.DistanciaLabel_2.Text = 'Distancia';
            app.distancia2 = uieditfield(app.RightPanel, 'numeric');
            app.distancia2.Value = 0.1;
            app.DistanciaLabel_3 = uilabel(app.RightPanel);
            app.DistanciaLabel_3.Text = 'Distancia';
            app.distancia1 = uieditfield(app.RightPanel, 'numeric');
            app.distancia1.Value = 0.2;
            app.NmerodecargastotalesEditFieldLabel = uilabel(app.RightPanel);
            app.NmerodecargastotalesEditFieldLabel.Text = 'Número de cargas totales';
            app.ncargas = uieditfield(app.RightPanel, 'numeric');
            app.ncargas.Value = 150;
            app.SimuladordecampoparacargaslinealesLabel = uilabel(app.RightPanel);
            app.SimuladordecampoparacargaslinealesLabel.Text = 'Simulador de campo para cargas lineales';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '____________________________________________________________________';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '____________________________________________________________________';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '____________________________________________________________________';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '____________________________________________________________________';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.Simulacion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulador_de_Campo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Simulacion)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Simulacion)
        end
    end
end