classdef primer_entregable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Simulacion                    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        Carga2Label                   matlab.ui.control.Label
        Carga1Label                   matlab.ui.control.Label
        SimularButton                 matlab.ui.control.StateButton
        ValoresfinalesLabel           matlab.ui.control.Label
        xEditFieldLabel               matlab.ui.control.Label
        x1                            matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        y1                            matlab.ui.control.NumericEditField
        xEditField_2Label             matlab.ui.control.Label
        x2                            matlab.ui.control.NumericEditField
        yEditField_2Label             matlab.ui.control.Label
        y2                            matlab.ui.control.NumericEditField
        DistanciaxLabel               matlab.ui.control.Label
        dx                            matlab.ui.control.NumericEditField
        CargapuntualEditFieldLabel    matlab.ui.control.Label
        carga1                        matlab.ui.control.NumericEditField
        CargapuntualEditField_2Label  matlab.ui.control.Label
        carga2                        matlab.ui.control.NumericEditField
        DistanciayLabel               matlab.ui.control.Label
        dy                            matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Simulacion,'center');
        end

        % Value changed function: SimularButton
        function SimularButtonValueChanged(app, event)
            
            cla(app.UIAxes,'reset')
            
            x1 = app.x1.Value;
            y1 = app.y1.Value;
            
            x2 = app.x2.Value;
            y2 = app.y2.Value;
            
            carga1 = app.carga1.Value;
            carga2 = app.carga2.Value;
            
            puntos = 25;
            cargas=[app.carga1.Value,app.carga2.Value];
            radio = 4;
            
            xcargas=[x1+0.5,x2+0.5];
            ycargas=[y1+0.5,y2+0.5];
            
            epsilon = 8.854e-12;%Escribir el valor de epsilon
            %es un valor para hacer la ecuacion del campo electrico
            k = 1/(4 * pi * epsilon);
            
            %Dimensiones de la grafica
            minx= x1-1;
            maxx= x2+2;
            
            
            if y1>y2
                maxy = y1+2;
                miny= y2-1.5;
            elseif y1<y2
                maxy = y2+2;
                miny= y1-1.5;
            else
                maxy = y1+2;
                miny= y1-1.5;
            end
            
            
            %campo electrico
            campoex = zeros(puntos);
            campoey = zeros(puntos);
            
            %Crear 2 vectores
                 %minimo y maximo para numero de divisiones
            x =linspace(minx,maxx,puntos);%va de menos -1.5 a 1.5 dividido en npuntos
            y =linspace(miny,maxy,puntos);%estos son vectores
            
            %crear malla de puntos
            [xpuntosmalla,ypuntosmalla]=meshgrid(x,y);
            
            %crear un for que recorra las cargas para poder obtener el calculo de campo
            %electrico
            
            for i=1:2
                dx = xpuntosmalla-xcargas(i);%obtiene distancias
                dy = ypuntosmalla-ycargas(i);%obtiene distancias
                
                r = sqrt(dx.^2+dy.^2);%distancias totales/ magnitud
                r3 = r.^3;%distnacias entre donde esta la carga y los elementos que rodean la carga
                
                %aqui es por componentes
                campoex=campoex+k.*cargas(i).*dx./r3;
                campoey=campoey+k.*cargas(i).*dy./r3;
                
            end
            
            campoe_total = sqrt(campoex.^2+campoey.^2);
            disp(campoe_total);
            
            %graficar
            
            quiver(app.UIAxes,xpuntosmalla,ypuntosmalla,campoex./campoe_total,campoey./campoe_total);%se aplica normalizacion
            
            
            app.dx.Value = abs((x2+0.5)-(x1+0.5));
            app.dy.Value = abs((y2+0.5)-(y1+0.5))
            
            txt = '+';
            txt2 = '_';
            
            
            %carga 1
            if carga1>0 %carga positiva
                rectangle(app.UIAxes,'Position',[x1 y1 1 1.2],'Curvature',[1,1],'FaceColor','b','EdgeColor',[0 0 1]);
                txt = '+';
                text(app.UIAxes,x1+0.25,y1+0.625,txt,'FontSize',95, 'Color', 'w');
            elseif carga1 <0 %carga negativa
                rectangle(app.UIAxes,'Position',[x1 y1 1 1.2],'Curvature',[1,1],'FaceColor','r','EdgeColor',[1 0 0]);
                txt = '_';
                text(app.UIAxes,x1+0.25,y1+1,txt,'FontSize',95, 'Color', 'w');
            else %carga neutra
                rectangle(app.UIAxes,'Position',[x1 y1 1 1.2],'Curvature',[1,1],'FaceColor','k','EdgeColor',[1 1 1]);
            end
            
            
            %carga 2
            if carga2>0
                %carga positiva*
                rectangle(app.UIAxes,'Position',[x2 y2 1 1.2],'Curvature',[1,1],'FaceColor','b','EdgeColor',[0 0 1]);
                txt2 = '+';
                text(app.UIAxes,x2+0.25,y2+0.25,txt2,'FontSize',95, 'Color', 'w');
            elseif carga2 <0
                rectangle(app.UIAxes,'Position',[x2 y2 1 1.2],'Curvature',[1,1],'FaceColor','r','EdgeColor',[1 0 0]);
                txt2 = '_';
                text(app.UIAxes,x2+0.25,y2+1,txt2,'FontSize',95, 'Color', 'w');
            else
                rectangle(app.UIAxes,'Position',[x2 y2 1 1.2],'Curvature',[1,1],'FaceColor','k','EdgeColor',[1 1 1]);
            end
            
            axis(app.UIAxes,[minx maxy miny maxy]);
            
            title(app.UIAxes,'Dos cargas con signos opuestos');
            xlabel(app.UIAxes,'X (m)');
            ylabel(app.UIAxes,'Y (m)');
        %}
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Simulacion.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {517, 517};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {636, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Simulacion = uifigure('Visible', 'off');
            app.Simulacion.Name = 'UI Figure';
            app.Simulacion.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Simulacion);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Dos cargas con signos opuestos')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            app.RightPanel = uipanel(app.GridLayout);
            app.Carga2Label = uilabel(app.RightPanel);
            app.Carga2Label.Text = 'Carga 2';
            app.Carga1Label = uilabel(app.RightPanel);
            app.Carga1Label.Text = 'Carga 1';
            app.SimularButton = uibutton(app.RightPanel, 'state');
            app.SimularButton.ValueChangedFcn = createCallbackFcn(app, @SimularButtonValueChanged, true);
            app.SimularButton.Text = 'Simular';
            app.ValoresfinalesLabel = uilabel(app.RightPanel);
            app.ValoresfinalesLabel.Text = 'Valores finales';
            app.xEditFieldLabel = uilabel(app.RightPanel);
            app.xEditFieldLabel.Text = 'x';
            app.x1 = uieditfield(app.RightPanel, 'numeric');
            app.x1.Value = 2;
            app.yEditFieldLabel = uilabel(app.RightPanel);
            app.yEditFieldLabel.Text = 'y';
            app.y1 = uieditfield(app.RightPanel, 'numeric');
            app.y1.Value = 4;
            app.xEditField_2Label = uilabel(app.RightPanel);
            app.xEditField_2Label.Text = 'x';
            app.x2 = uieditfield(app.RightPanel, 'numeric');
            app.x2.Value = 4;
            app.yEditField_2Label = uilabel(app.RightPanel);
            app.yEditField_2Label.Text = 'y';
            app.y2 = uieditfield(app.RightPanel, 'numeric');
            app.y2.Value = 4;
            app.DistanciaxLabel = uilabel(app.RightPanel);
            app.DistanciaxLabel.Text = 'Distancia  x';
            app.dx = uieditfield(app.RightPanel, 'numeric');
            app.CargapuntualEditFieldLabel = uilabel(app.RightPanel);
            app.CargapuntualEditFieldLabel.Text = 'Carga puntual';
            app.carga1 = uieditfield(app.RightPanel, 'numeric');
            app.carga1.Value = 2;
            app.CargapuntualEditField_2Label = uilabel(app.RightPanel);
            app.CargapuntualEditField_2Label.Text = 'Carga puntual';
            app.carga2 = uieditfield(app.RightPanel, 'numeric');
            app.carga2.Value = -2;
            app.DistanciayLabel = uilabel(app.RightPanel);
            app.DistanciayLabel.Text = 'Distancia  y';
            app.dy = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.Simulacion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = primer_entregable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Simulacion)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Simulacion)
        end
    end
end