classdef tarea3_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewtonRaphson         matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        x23Button             matlab.ui.control.Button
        cosxx3Button          matlab.ui.control.Button
        x3x16Button           matlab.ui.control.Button
        x42x1Button           matlab.ui.control.Button
        FuncionesLabel        matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        xEditField            matlab.ui.control.NumericEditField
        IteracionesEditField  matlab.ui.control.NumericEditField
        IteracionesLabel      matlab.ui.control.Label
        yEditField            matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        ValorXLabel           matlab.ui.control.Label
        ValorYLabel           matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %Juan Carlos Garfias Tovar A01652138
    methods (Access = private)
        
        function [r,xi,iter] = NewtonR(~,f,fdx,xi,error)
            iter = 0;
            
            while abs(subs(f,xi))>error
                xi = xi - subs(f,xi)/subs(fdx,xi);
                iter = iter +1;
            end
            
            r = double(subs(f,xi));
            xi= double(xi);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.NewtonRaphson,'center');
        end

        % Button pushed function: x23Button
        function x23ButtonPushed(app, event)
            syms x
            cla(app.UIAxes,'reset');
            
            hold( app.UIAxes, "off" )
            f1 = (-1/10)*x^2+3;
            b1 = -15:0.1:15;
            y1 = subs(f1,b1);
            x1 = 10;
            fdx1 = diff(f1);%obtiene derivada
            error = 10^-10;
            
            [r,xi,iter] = NewtonR(f1,fdx1,x1,error);
            
            plot(app.UIAxes,xi,r,'o','Color','#FF5000')
            text(app.UIAxes,xi,r,'\leftarrow Punto Calculado')
            tname = strcat('X=',num2str(xi)," Iteraciones:",num2str(iter))
            title(app.UIAxes,tname)
            hold( app.UIAxes, 'on' )
            grid(app.UIAxes,'on')
            plot(app.UIAxes,b1,y1,'Color','#0072BD')
            xlabel(app.UIAxes,'X') 
            ylabel(app.UIAxes,'Y')
            
            app.xEditField.Value = xi;
            app.yEditField.Value = r;
            app.IteracionesEditField.Value = iter;
        end

        % Button pushed function: cosxx3Button
        function cosxx3ButtonPushed(app, event)
            syms x
            cla(app.UIAxes,'reset');
            
            hold( app.UIAxes, "off" )
            f2 = cos(x)-x^3;
            b2 = -3:0.1:4;
            y2 = subs(f2,b2);
            x2 = 1;
            fdx2 = diff(f2);%obtiene derivada
            
            error = 10^-10;
            
            [r,xi,iter] = NewtonR(f2,fdx2,x2,error);
            
            plot(app.UIAxes,xi,r,'o')
            text(app.UIAxes,xi,r,'\leftarrow Punto Calculado')
            tname = strcat('X=',num2str(xi)," Iteraciones:",num2str(iter))
            title(app.UIAxes,tname)
            hold( app.UIAxes, 'on' )
            grid(app.UIAxes,'on')
            plot(app.UIAxes,b2,y2,'Color','#D95319')
            xlabel(app.UIAxes,'X') 
            ylabel(app.UIAxes,'Y') 
            
            app.xEditField.Value = xi;
            app.yEditField.Value = r;
            app.IteracionesEditField.Value = iter;
            
        end

        % Button pushed function: x3x16Button
        function x3x16ButtonPushed(app, event)
            syms x
            cla(app.UIAxes,'reset');
            
            hold( app.UIAxes, "off" )
            
            f3 = x^3+x+16;
            b3 = -6:0.1:6;
            y3 = subs(f3,b3);
            x3 = -2;
            fdx3 = diff(f3);%obtiene derivada
            
            error = 10^-10;
            
            [r,xi,iter] = NewtonR(f3,fdx3,x3,error);
            
            plot(app.UIAxes,xi,r,'o','Color','#800080')
            text(app.UIAxes,xi,r,'\leftarrow Punto Calculado')
            tname = strcat('X=',num2str(xi)," Iteraciones:",num2str(iter))
            title(app.UIAxes,tname)
            hold( app.UIAxes, 'on' )
            grid(app.UIAxes,'on')
            plot(app.UIAxes,b3,y3,'Color','#EDB120')
            xlabel(app.UIAxes,'X') 
            ylabel(app.UIAxes,'Y') 
            
            app.xEditField.Value = xi;
            app.yEditField.Value = r;
            app.IteracionesEditField.Value = iter;
        end

        % Button pushed function: x42x1Button
        function x42x1ButtonPushed(app, event)
            syms x
            cla(app.UIAxes,'reset');
            
            hold( app.UIAxes, "off" )
            
            f4 = x^4+2*x-1;
            b4 = -2:0.1:4;
            y4 = subs(f4,b4);
            x4 = 2;
            fdx4 = diff(f4);%obtiene derivada
            
            error = 10^-10;
            
            [r,xi,iter] = NewtonR(f4,fdx4,x4,error);
            
            plot(app.UIAxes,xi,r,'o','Color','#FF0000')
            text(app.UIAxes,xi,r,'\leftarrow Punto Calculado')
            tname = strcat('X=',num2str(xi)," Iteraciones:",num2str(iter))
            title(app.UIAxes,tname)
            hold( app.UIAxes, 'on' )
            grid(app.UIAxes,'on')
            plot(app.UIAxes,b4,y4,'Color','#77AC30')
            xlabel(app.UIAxes,'X') 
            ylabel(app.UIAxes,'Y') 
            
            app.xEditField.Value = xi;
            app.yEditField.Value = r;
            app.IteracionesEditField.Value = iter;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NewtonRaphson.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {536, 536};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {604, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewtonRaphson = uifigure('Visible', 'off');
            app.NewtonRaphson.Name = 'Newton Raphson';
            app.NewtonRaphson.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.NewtonRaphson);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.x23Button = uibutton(app.RightPanel, 'push');
            app.x23Button.ButtonPushedFcn = createCallbackFcn(app, @x23ButtonPushed, true);
            app.x23Button.Text = '(-1/10)x^2+3';
            app.cosxx3Button = uibutton(app.RightPanel, 'push');
            app.cosxx3Button.ButtonPushedFcn = createCallbackFcn(app, @cosxx3ButtonPushed, true);
            app.cosxx3Button.Text = 'cos(x)-x^3';
            app.x3x16Button = uibutton(app.RightPanel, 'push');
            app.x3x16Button.ButtonPushedFcn = createCallbackFcn(app, @x3x16ButtonPushed, true);
            app.x3x16Button.Text = 'x^3+x+16';
            app.x42x1Button = uibutton(app.RightPanel, 'push');
            app.x42x1Button.ButtonPushedFcn = createCallbackFcn(app, @x42x1ButtonPushed, true);
            app.x42x1Button.Text = 'x^4+2x-1';
            app.FuncionesLabel = uilabel(app.RightPanel);
            app.FuncionesLabel.Text = 'Funciones';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '________________';
            app.xEditField = uieditfield(app.RightPanel, 'numeric');
            app.yEditField = uieditfield(app.RightPanel, 'numeric');
            app.IteracionesEditField = uieditfield(app.RightPanel, 'numeric');
            app.IteracionesLabel = uilabel(app.RightPanel);
            app.IteracionesLabel.Text = 'Iteraciones';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '________________';
            app.ValorXLabel = uilabel(app.RightPanel);
            app.ValorXLabel.Text = 'Valor X';
            app.ValorYLabel = uilabel(app.RightPanel);
            app.ValorYLabel.Text = 'Valor Y';
            % Show the figure after all components are created
            app.NewtonRaphson.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tarea3_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewtonRaphson)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewtonRaphson)
        end
    end
end