classdef Entregable1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimuladorDipoloUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        SimularButton                   matlab.ui.control.StateButton
        RadiomLabel                     matlab.ui.control.Label
        Radio                           matlab.ui.control.NumericEditField
        TmALabel                        matlab.ui.control.Label
        Miu                             matlab.ui.control.NumericEditField
        CorrienteALabel                 matlab.ui.control.Label
        Corriente                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SimuladordedipolomagnticoLabel  matlab.ui.control.Label
        OrientacindeldipoloSwitchLabel  matlab.ui.control.Label
        OrientacindeldipoloSwitch       matlab.ui.control.Switch
        TipodegrficaLabel               matlab.ui.control.Label
        TipodegrficaSwitch              matlab.ui.control.Switch
        Button                          matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function horizontal(app)
            %%Preambulo de quivers, se define la malla y sus parametros de tamaño
            npuntos = 60;                   % Cantidad de agujas              
            maxx = 100;
            minx= -maxx;
            maxy = 100;
            miny = -maxy;
            X = linspace(minx,maxx, npuntos);
            Y = linspace(miny,maxy, npuntos);
            [xquivers,yquivers] = meshgrid(X,Y);
            %% Se definen los vectores para almacenar los calculos de componentes
            bex = zeros(npuntos);
            bey = zeros(npuntos);
            % Parametros básicos
            miu = app.Miu.Value;
            current = app.Corriente.Value;
            radius = app.Radio.Value;
            % Se realizan los cálculos de campo mag. sobre cada componente
            deltay = xquivers;
            deltax = yquivers;
            R = sqrt(deltax.^2 + deltay.^2);
            bex = bex + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltay.*deltax) ./(R.^2)));
            bey = bey + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltax.^2) ./(R.^2) -1)) ;
             
            bet = sqrt(bex.^2 + bey.^2); % Obtenemos magnitud absoluta de cada punto
            % Renderizado
            if(app.TipodegrficaSwitch.Value == "Quiver")
                quiver(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet,'linewidth',0.8, "Color",'blue');
            else
                streamslice(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet);
            end
            hold(app.UIAxes,"on");
            % line([0,0],[-radius/2,radius/2],"Linewidth",6,"Color","r"); <- En caso de
            % querer una linea vertical se invierten los parámetros
            line(app.UIAxes,[-radius/2,radius/2],[0,0],"Linewidth",6,"Color","red");
            axis(app.UIAxes,[(-radius/2)-60 (radius/2)+60 -100 100]);
        end
        
        function vertical(app)
            %%Preambulo de quivers, se define la malla y sus parametros de tamaño
            npuntos = 60;                   % Cantidad de agujas              
            maxx = 100;
            minx= -maxx;
            maxy = 100;
            miny = -maxy;
            X = linspace(minx,maxx, npuntos);
            Y = linspace(miny,maxy, npuntos);
            [xquivers,yquivers] = meshgrid(X,Y);
            %% Se definen los vectores para almacenar los calculos de componentes
            bex = zeros(npuntos);
            bey = zeros(npuntos);
            % Parametros básicos
            miu = app.Miu.Value;
            current = app.Corriente.Value;
            radius = app.Radio.Value;
            % Se realizan los cálculos de campo mag. sobre cada componente
            deltay = xquivers;
            deltax = yquivers;
            R = sqrt(deltax.^2 + deltay.^2);
            bey = -1*(bey + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltax.*deltay) ./(R.^2))) ); %Giro de 90 grados
            bex = -1*(bex + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltay.^2) ./(R.^2) -1)) );
             
            bet = sqrt(bex.^2 + bey.^2); % Obtenemos magnitud absoluta de cada punto
            % Renderizado
            if(app.TipodegrficaSwitch.Value == "Quiver")
                quiver(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet,'linewidth',0.8, "Color",'blue');
            else
                streamslice(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet);
            end
            hold(app.UIAxes,"on");
            line(app.UIAxes,[0,0],[-radius/2,radius/2],"Linewidth",6,"Color","r"); %<- En caso de
            % querer una linea vertical se invierten los parámetros
            %line(app.UIAxes,[-radius/2,radius/2],[0,0],"Linewidth",6,"Color","red");
            axis(app.UIAxes,[-100 100 (-radius/2)-60 (radius/2)+60]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SimuladorDipoloUIFigure,'center');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SimuladorDipoloUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {648, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: SimularButton
        function SimularButtonValueChanged(app, event)
            cla(app.UIAxes,'reset');
            clc
            grid(app.UIAxes,"on")
            if(app.OrientacindeldipoloSwitch.Value == "Horizontal")
                horizontal(app);
            else
                vertical(app);
            end
            title(app.UIAxes,'Campo magnetico de un dipolo');
            xlabel(app.UIAxes,'X (m)');
            ylabel(app.UIAxes,'Y (m)');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            decodedValue = "	|Modelación computacional de sistemas electromagnéticos| Equipo: Juan Carlos Garfias Tovar, A01652138,Bo Hyeon Cha, A01023804, Francisco Zamora Treviño, A01570484 (ITESM, 2020)";
            
            fig = figure("Name",'Informacion');
            hPan = uipanel(fig,'Units','normalized');

            uicontrol(hPan, 'Style','text', 'HorizontalAlignment','center', ...
            'FontSize',10, 'Units','normalized', 'Position',[0.2 0.4 0.6 0.2], ...
            'String',decodedValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimuladorDipoloUIFigure = uifigure('Visible', 'off');
            app.SimuladorDipoloUIFigure.Name = 'SimuladorDipolo';
            app.SimuladorDipoloUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SimuladorDipoloUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.SimularButton = uibutton(app.RightPanel, 'state');
            app.SimularButton.ValueChangedFcn = createCallbackFcn(app, @SimularButtonValueChanged, true);
            app.SimularButton.Text = 'Simular';
            app.RadiomLabel = uilabel(app.RightPanel);
            app.RadiomLabel.Text = 'Radio (m)';
            app.Radio = uieditfield(app.RightPanel, 'numeric');
            app.Radio.Value = 40;
            app.TmALabel = uilabel(app.RightPanel);
            app.TmALabel.Text = ' μ (T*m/A)';
            app.Miu = uieditfield(app.RightPanel, 'numeric');
            app.Miu.Value = 1.2566e-06;
            app.CorrienteALabel = uilabel(app.RightPanel);
            app.CorrienteALabel.Text = {'Corriente (A)'; ''};
            app.Corriente = uieditfield(app.RightPanel, 'numeric');
            app.Corriente.Value = -1;
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '______________________________________________________';
            app.SimuladordedipolomagnticoLabel = uilabel(app.RightPanel);
            app.SimuladordedipolomagnticoLabel.Text = 'Simulador de dipolo magnético';
            app.OrientacindeldipoloSwitchLabel = uilabel(app.RightPanel);
            app.OrientacindeldipoloSwitchLabel.Text = 'Orientación del dipolo';
            app.OrientacindeldipoloSwitch = uiswitch(app.RightPanel, 'slider');
            app.OrientacindeldipoloSwitch.Items = {'Horizontal', 'Vertical'};
            app.OrientacindeldipoloSwitch.Value = 'Horizontal';
            app.TipodegrficaLabel = uilabel(app.RightPanel);
            app.TipodegrficaLabel.Text = 'Tipo de gráfica';
            app.TipodegrficaSwitch = uiswitch(app.RightPanel, 'slider');
            app.TipodegrficaSwitch.Items = {'Streamline', 'Quiver'};
            app.TipodegrficaSwitch.Value = 'Streamline';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.SimuladorDipoloUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entregable1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimuladorDipoloUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimuladorDipoloUIFigure)
        end
    end
end