classdef Segundo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SImuladordefrenadoUIFigure    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        SimulacionButton              matlab.ui.control.Button
        SimuladordefrenadoporunanillodecorrienteLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SimulacionTab                 matlab.ui.container.Tab
        MaterialDropDownLabel         matlab.ui.control.Label
        MaterialDropDown              matlab.ui.control.DropDown
        TiempoinicialsEditFieldLabel  matlab.ui.control.Label
        TiempoinicialsEditField       matlab.ui.control.NumericEditField
        AlturamEditFieldLabel         matlab.ui.control.Label
        AlturamEditField              matlab.ui.control.NumericEditField
        RadiomEditFieldLabel          matlab.ui.control.Label
        RadiomEditField               matlab.ui.control.NumericEditField
        MasakgEditFieldLabel          matlab.ui.control.Label
        MasakgEditField               matlab.ui.control.NumericEditField
        TiempofinalsEditFieldLabel    matlab.ui.control.Label
        TiempofinalsEditField         matlab.ui.control.NumericEditField
        DatosTab                      matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        Button                        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SImuladordefrenadoUIFigure,'center');
        end

        % Button pushed function: SimulacionButton
        function SimulacionButtonPushed(app, event)
 
            t0 = app.TiempoinicialsEditField.Value;
            tf = app.TiempofinalsEditField.Value;
            t = [t0,tf]; %tiempo
            zdata = [app.AlturamEditField.Value,0]; % Cae de 1m con una velocidad de 0m/s (inicial)
            
            [t,z] = ode45(@fall,t, zdata);
            plot(app.UIAxes,t,z(:,1),'LineWidth',2);
            xlabel(app.UIAxes,"Tiempo (s)")
            ylabel(app.UIAxes,"Posicion (m)")
            legend(app.UIAxes,'Posicion del dipolo')
            title(app.UIAxes,"Posicion vs Tiempo")
            
            d = z(:,1);
            %disp(t)
            data = [t d]
            set(app.UITable,'Data',data);
            
            function sistema = fall(t,z)
                %     Z siendo un vector con los intervalos de Z inicial y diferencial de Z en ese punto,
                %     requerido asi por ODE45
                    m = app.MasakgEditField.Value;             % MASA
                    g = 9.81;           % GRAV
    		        if(app.MaterialDropDown.Value =="Hierro")
                        RP = 5000  ;         % PERMEABILIDAD RELATIVA MAXIMA HIERRO
                        R = 9.71 *10^-8;    % RESISTIVIDAD HIERRO 20°C
                    elseif(app.MaterialDropDown.Value =="Acero inoxidable ferritico")
                        RP = 1000   ;         % PERMEABILIDAD RELATIVA MAXIMA ACERO
                        R = 1.8*10^-7;    % RESISTIVIDAD ACERO 20°C
                    elseif(app.MaterialDropDown.Value =="Niquel")
                        RP = 600  ;         % PERMEABILIDAD RELATIVA MAXIMA 45 Nickel
                        R = 7.8*10^-8;    % RESISTIVIDAD Nickel 20°C               
                    end                      
                    mu = 4*pi*(10^-7);  % PERMEABILIDAD MAG. K
                    a = app.RadiomEditField.Value;           % RADIO
                   
                % IMPLEMENTACIÓN DE ECUACION INICIAL
                    k = (9*(mu * RP)^2 * (a^4))/(4*R);
                    elem_1 = z(2);
                    elem_2 = -((k.*(z(1).^2).*z(2))) ./(m.*((z(1).^2 + a^2).^5)) - g;
                   
                    sistema = [elem_1; elem_2];
            end
               
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SImuladordefrenadoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {466, 466};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {537, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            decodedValue = "	|Modelación computacional de sistemas electromagnéticos| Equipo: Juan Carlos Garfias Tovar, A01652138,Bo Hyeon Cha, A01023804, Francisco Zamora Treviño, A01570484 (ITESM, 2020)";
            
            fig = figure("Name",'Informacion');
            hPan = uipanel(fig,'Units','normalized');

            uicontrol(hPan, 'Style','text', 'HorizontalAlignment','center', ...
            'FontSize',10, 'Units','normalized', 'Position',[0.2 0.4 0.6 0.2], ...
            'String',decodedValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SImuladordefrenadoUIFigure = uifigure('Visible', 'off');
            app.SImuladordefrenadoUIFigure.Name = 'SImulador de frenado';
            app.SImuladordefrenadoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SImuladordefrenadoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.SimulacionButton = uibutton(app.RightPanel, 'push');
            app.SimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @SimulacionButtonPushed, true);
            app.SimulacionButton.Text = 'Simulacion';
            app.SimuladordefrenadoporunanillodecorrienteLabel = uilabel(app.RightPanel);
            app.SimuladordefrenadoporunanillodecorrienteLabel.Text = 'Simulador de frenado por un anillo de corriente';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '_________________________________________';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SimulacionTab = uitab(app.TabGroup);
            app.SimulacionTab.Title = 'Simulacion';
            app.MaterialDropDownLabel = uilabel(app.SimulacionTab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.SimulacionTab);
            app.MaterialDropDown.Items = {'Hierro', 'Acero inoxidable ferritico', 'Niquel'};
            app.MaterialDropDown.Value = 'Hierro';
            app.TiempoinicialsEditFieldLabel = uilabel(app.SimulacionTab);
            app.TiempoinicialsEditFieldLabel.Text = 'Tiempo inicial (s)';
            app.TiempoinicialsEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.AlturamEditFieldLabel = uilabel(app.SimulacionTab);
            app.AlturamEditFieldLabel.Text = 'Altura (m)';
            app.AlturamEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.AlturamEditField.Value = 1;
            app.RadiomEditFieldLabel = uilabel(app.SimulacionTab);
            app.RadiomEditFieldLabel.Text = 'Radio (m)';
            app.RadiomEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.RadiomEditField.Value = 0.08;
            app.MasakgEditFieldLabel = uilabel(app.SimulacionTab);
            app.MasakgEditFieldLabel.Text = 'Masa (kg)';
            app.MasakgEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.MasakgEditField.Value = 0.01;
            app.TiempofinalsEditFieldLabel = uilabel(app.SimulacionTab);
            app.TiempofinalsEditFieldLabel.Text = 'Tiempo final (s)';
            app.TiempofinalsEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.TiempofinalsEditField.Value = 40;
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.UITable = uitable(app.DatosTab);
            app.UITable.ColumnName = {'Tiempo'; 'Posicion'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.SImuladordefrenadoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segundo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SImuladordefrenadoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SImuladordefrenadoUIFigure)
        end
    end
end