classdef Segundo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SImuladordefrenadoUIFigure   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        SimulacionButton             matlab.ui.control.Button
        SimuladordefrenadoporunanillodecorrienteLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        SimulacionTab                matlab.ui.container.Tab
        MaterialDropDownLabel        matlab.ui.control.Label
        MaterialDropDown             matlab.ui.control.DropDown
        TiempoinicialEditFieldLabel  matlab.ui.control.Label
        TiempoinicialEditField       matlab.ui.control.NumericEditField
        AlturaEditFieldLabel         matlab.ui.control.Label
        AlturaEditField              matlab.ui.control.NumericEditField
        RadioEditFieldLabel          matlab.ui.control.Label
        RadioEditField               matlab.ui.control.NumericEditField
        MasaEditFieldLabel           matlab.ui.control.Label
        MasaEditField                matlab.ui.control.NumericEditField
        TiempofinalEditFieldLabel    matlab.ui.control.Label
        TiempofinalEditField         matlab.ui.control.NumericEditField
        DatosTab                     matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        Button                       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SimuladorDipoloUIFigure,'center');
        end

        % Button pushed function: SimulacionButton
        function SimulacionButtonPushed(app, event)
                
            t0 = app.TiempoinicialEditField.Value;
            tf = app.TiempofinalEditField.Value;
            t = [t0,tf]; %tiempo
            zdata = [app.AlturaEditField.Value,0]; % Cae de 10m con una velocidad de 0m/s (inicial)
            
            [t,z] = ode45(@fall,t, zdata);
            plot(app.UIAxes,t,z(:,1));
            xlabel(app.UIAxes,"Tiempo")
            ylabel(app.UIAxes,"Posicion")
            legend(app.UIAxes,'Posicion')
            
            d = z(:,1);
            %disp(t)
            data = [t d]
            set(app.UITable,'Data',data);
            
            function sistema = fall(t,z)
                %     Z siendo un vector con los intervalos de Z inicial y diferencial de Z en ese punto,
                %     requerido asi por ODE45
                    m = app.MasaEditField.Value;             % MASA
                    g = 9.81;           % GRAV
    		        if(app.MaterialDropDown.Value =="Hierro")
                        RP =5000  ;         % PERMEABILIDAD RELATIVA MAXIMA HIERRO
                        R = 9.71 *10^-8;    % RESISTIVIDAD HIERRO 20°C
                    elseif(app.MaterialDropDown.Value =="Acero")
                        RP =100  ;         % PERMEABILIDAD RELATIVA MAXIMA ACERO
                        R = 72.00*10^-8;    % RESISTIVIDAD ACERO 20°C
                    elseif(app.MaterialDropDown.Value =="45 Permalloy")
                        RP =50000  ;         % PERMEABILIDAD RELATIVA MAXIMA 45 Permalloy
                        R = 66;    % RESISTIVIDAD 45 Permalloy 20°C               
                    end                      
                    mu = 4*pi*(10^-7);  % PERMEABILIDAD MAG. K
                    
                    a = app.RadioEditField.Value;           % RADIO
                   
                % IMPLEMENTACIÓN DE ECUACION INICIAL
                    k = (9*(mu * RP)^2 * (a^4))/(4*R);
                    elem_1 = z(2);
                    elem_2 = -((k.*(z(1).^2).*z(2))) ./(m.*((z(1).^2 + a^2).^5)) - g;
                   
                    sistema = [elem_1; elem_2];
            end
               
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            decodedValue = "	|Modelación computacional de sistemas electromagnéticos| Equipo: Juan Carlos Garfias Tovar, A01652138,Bo Hyeon Cha, A01023804, Francisco Zamora Treviño, A01570484 (ITESM, 2020)";
            
            fig = figure("Name",'Informacion');
            hPan = uipanel(fig,'Units','normalized');

            uicontrol(hPan, 'Style','text', 'HorizontalAlignment','center', ...
            'FontSize',10, 'Units','normalized', 'Position',[0.2 0.4 0.6 0.2], ...
            'String',decodedValue);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SImuladordefrenadoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {466, 466};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {537, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SImuladordefrenadoUIFigure = uifigure('Visible', 'off');
            app.SImuladordefrenadoUIFigure.Name = 'SImulador de frenado';
            app.SImuladordefrenadoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SImuladordefrenadoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.SimulacionButton = uibutton(app.RightPanel, 'push');
            app.SimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @SimulacionButtonPushed, true);
            app.SimulacionButton.Text = 'Simulacion';
            app.SimuladordefrenadoporunanillodecorrienteLabel = uilabel(app.RightPanel);
            app.SimuladordefrenadoporunanillodecorrienteLabel.Text = 'Simulador de frenado por un anillo de corriente';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '_________________________________________';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SimulacionTab = uitab(app.TabGroup);
            app.SimulacionTab.Title = 'Simulacion';
            app.MaterialDropDownLabel = uilabel(app.SimulacionTab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.SimulacionTab);
            app.MaterialDropDown.Items = {'Hierro', 'Acero', '45 Permalloy'};
            app.MaterialDropDown.Value = 'Hierro';
            app.TiempoinicialEditFieldLabel = uilabel(app.SimulacionTab);
            app.TiempoinicialEditFieldLabel.Text = 'Tiempo inicial';
            app.TiempoinicialEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.AlturaEditFieldLabel = uilabel(app.SimulacionTab);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.AlturaEditField.Value = 1;
            app.RadioEditFieldLabel = uilabel(app.SimulacionTab);
            app.RadioEditFieldLabel.Text = 'Radio';
            app.RadioEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.RadioEditField.Value = 0.08;
            app.MasaEditFieldLabel = uilabel(app.SimulacionTab);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.MasaEditField.Value = 0.01;
            app.TiempofinalEditFieldLabel = uilabel(app.SimulacionTab);
            app.TiempofinalEditFieldLabel.Text = 'Tiempo final';
            app.TiempofinalEditField = uieditfield(app.SimulacionTab, 'numeric');
            app.TiempofinalEditField.Value = 40;
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.UITable = uitable(app.DatosTab);
            app.UITable.ColumnName = {'Tiempo'; 'Posicion'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.SImuladordefrenadoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segundo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SImuladordefrenadoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SImuladordefrenadoUIFigure)
        end
    end
end