classdef TercerEntregable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FrenadomagnticoTab              matlab.ui.container.Tab
        MasaSliderLabel                 matlab.ui.control.Label
        MasaSlider                      matlab.ui.control.Slider
        MetalDropDownLabel              matlab.ui.control.Label
        MetalDropDown                   matlab.ui.control.DropDown
        AlturainicialSliderLabel        matlab.ui.control.Label
        AlturainicialSlider             matlab.ui.control.Slider
        RadioEditFieldLabel             matlab.ui.control.Label
        RadioEditField                  matlab.ui.control.NumericEditField
        TiempoSliderLabel               matlab.ui.control.Label
        TiempoSlider                    matlab.ui.control.Slider
        Graph                           matlab.ui.container.Panel
        posAxes                         matlab.ui.control.UIAxes
        velAxes                         matlab.ui.control.UIAxes
        forceAxes                       matlab.ui.control.UIAxes
        accAxes                         matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        CampomagnticoTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        SimularButton                   matlab.ui.control.StateButton
        RadioLabel                      matlab.ui.control.Label
        Radio                           matlab.ui.control.NumericEditField
        TmALabel                        matlab.ui.control.Label
        Miu                             matlab.ui.control.NumericEditField
        CorrienteALabel                 matlab.ui.control.Label
        Corriente                       matlab.ui.control.NumericEditField
        OrientacindeldipoloSwitchLabel  matlab.ui.control.Label
        OrientacindeldipoloSwitch       matlab.ui.control.Switch
        TipodegrficaSwitchLabel         matlab.ui.control.Label
        TipodegrficaSwitch              matlab.ui.control.Switch
        Button_2                        matlab.ui.control.Button
        SimuladordecadadeundipolomagnticofrenadoporunanilloLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end


    methods (Access = private)
        
        function sistema = fall(app,t,z)
            %     Z siendo un vector con los intervalos de Z inicial y diferencial de Z en ese punto,
            %     requerido asi por ODE45
            if app.MetalDropDown.Value == "Hierro"
                  RP =5000;
                  R = 9.71 *10^-8;
            elseif app.MetalDropDown.Value == "Cobre"
                RP=0.999994;
                R = 1.68&10^-8;
            elseif app.MetalDropDown.Value == "Platino"
                RP = 1.000265;
                R = 10.6*10^-8;
            end
            m = app.MasaSlider.Value;             % MASA
            g = 9.81;           % GRAV
               % PERMEABILIDAD RELATIVA MAXIMA HIERRO
            % RESISTIVIDAD HIERRO 20°C
            mu = 4*pi*(10^-7);  % PERMEABILIDAD MAG. K
            a = app.RadioEditField.Value;           % RADIO
           
            % IMPLEMENTACIÓN DE ECUACION INICIAL
            k = (9*(mu * RP)^2 * (a^4))/(4*R);
            elem_1 = z(2);
            elem_2 = -((k.*(z(1).^2).*z(2))) ./(m.*((z(1).^2 + a^2).^5)) - g;
           
            sistema = [elem_1; elem_2];
        end
        
        function results = do_calc(app)
            t0 = 0;
            tf = app.TiempoSlider.Value;
            t = [t0,tf]; %tiempo
            z0 = app.AlturainicialSlider.Value;
            zdata = [z0,0];
            [t,z] = ode45(@app.fall, t, zdata);
            p = polyfit(t,z(:,1),12);
            yy = polyval(p,t);
            plot(app.posAxes,t,z(:,1));
            %derivada 1 velocidad

            d1 = polyder(p);
            yy2 = polyval(d1,t);
            plot(app.velAxes,t,yy2);
            
            %derivada 2 aceclearcion

            d2 = polyder(d1);
            yy3 = polyval(d2,t);
            plot(app.accAxes,t,yy3);
            
            %Fuerza
            f = yy3 .* app.MasaSlider.Value;
            plot(app.forceAxes,t,f);
        end
        
        function horizontal(app)
            %%Preambulo de quivers, se define la malla y sus parametros de tamaño
            npuntos = 60;                   % Cantidad de agujas              
            maxx = 100;
            minx= -maxx;
            maxy = 100;
            miny = -maxy;
            X = linspace(minx,maxx, npuntos);
            Y = linspace(miny,maxy, npuntos);
            [xquivers,yquivers] = meshgrid(X,Y);
            %% Se definen los vectores para almacenar los calculos de componentes
            bex = zeros(npuntos);
            bey = zeros(npuntos);
            % Parametros básicos
            miu = app.Miu.Value;
            current = app.Corriente.Value;
            radius = app.Radio.Value;
            % Se realizan los cálculos de campo mag. sobre cada componente
            deltay = xquivers;
            deltax = yquivers;
            R = sqrt(deltax.^2 + deltay.^2);
            bex = bex + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltay.*deltax) ./(R.^2)));
            bey = bey + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltax.^2) ./(R.^2) -1)) ;
             
            bet = sqrt(bex.^2 + bey.^2); % Obtenemos magnitud absoluta de cada punto
            % Renderizado
            if(app.TipodegrficaSwitch.Value == "Quiver")
                quiver(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet,'linewidth',0.8, "Color",'blue');
            else
                streamslice(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet);
            end
            hold(app.UIAxes,"on");
            % line([0,0],[-radius/2,radius/2],"Linewidth",6,"Color","r"); <- En caso de
            % querer una linea vertical se invierten los parámetros
            line(app.UIAxes,[-radius/2,radius/2],[0,0],"Linewidth",6,"Color","red");
            axis(app.UIAxes,[(-radius/2)-60 (radius/2)+60 -100 100]);
        end
        
        function vertical(app)
            %%Preambulo de quivers, se define la malla y sus parametros de tamaño
            npuntos = 60;                   % Cantidad de agujas              
            maxx = 100;
            minx= -maxx;
            maxy = 100;
            miny = -maxy;
            X = linspace(minx,maxx, npuntos);
            Y = linspace(miny,maxy, npuntos);
            [xquivers,yquivers] = meshgrid(X,Y);
            %% Se definen los vectores para almacenar los calculos de componentes
            bex = zeros(npuntos);
            bey = zeros(npuntos);
            % Parametros básicos
            miu = app.Miu.Value;
            current = app.Corriente.Value;
            radius = app.Radio.Value;
            % Se realizan los cálculos de campo mag. sobre cada componente
            deltay = xquivers;
            deltax = yquivers;
            R = sqrt(deltax.^2 + deltay.^2);
            bey = -1*(bey + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltax.*deltay) ./(R.^2))) ); %Giro de 90 grados
            bex = -1*(bex + ((miu * current .* radius^2 ./ (4.*R.^3)) .* ((3.*deltay.^2) ./(R.^2) -1)) );
             
            bet = sqrt(bex.^2 + bey.^2); % Obtenemos magnitud absoluta de cada punto
            % Renderizado
            if(app.TipodegrficaSwitch.Value == "Quiver")
                quiver(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet,'linewidth',0.8, "Color",'blue');
            else
                streamslice(app.UIAxes,xquivers,yquivers,-bex ./bet,-bey./bet);
            end
            hold(app.UIAxes,"on");
            line(app.UIAxes,[0,0],[-radius/2,radius/2],"Linewidth",6,"Color","r"); %<- En caso de
            % querer una linea vertical se invierten los parámetros
            %line(app.UIAxes,[-radius/2,radius/2],[0,0],"Linewidth",6,"Color","red");
            axis(app.UIAxes,[-100 100 (-radius/2)-60 (radius/2)+60]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            movegui(app.UIFigure,'center');
            do_calc(app);
        end

        % Value changed function: RadioEditField
        function RadioEditFieldValueChanged(app, event)

            do_calc(app);
        end

        % Value changed function: MetalDropDown
        function MetalDropDownValueChanged(app, event)

            do_calc(app);
        end

        % Value changed function: AlturainicialSlider
        function AlturainicialSliderValueChanged(app, event)

            do_calc(app);
        end

        % Value changed function: TiempoSlider
        function TiempoSliderValueChanged(app, event)

            do_calc(app);
        end

        % Value changed function: MasaSlider
        function MasaSliderValueChanged(app, event)

            do_calc(app);
        end

        % Value changed function: SimularButton
        function SimularButtonValueChanged(app, event)
            cla(app.UIAxes,'reset');
            clc
            grid(app.UIAxes,"on")
            if(app.OrientacindeldipoloSwitch.Value == "Horizontal")
                horizontal(app);
            else
                vertical(app);
            end
            title(app.UIAxes,'Campo magnetico de un dipolo');
            xlabel(app.UIAxes,'X (m)');
            ylabel(app.UIAxes,'Y (m)');
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            decodedValue = "	|Modelación computacional de sistemas electromagnéticos| Equipo: Juan Carlos Garfias Tovar, A01652138,Bo Hyeon Cha, A01023804, Francisco Zamora Treviño, A01570484 (ITESM, 2020)";
            
            fig = figure("Name",'Informacion');
            hPan = uipanel(fig,'Units','normalized');

            uicontrol(hPan, 'Style','text', 'HorizontalAlignment','center', ...
            'FontSize',10, 'Units','normalized', 'Position',[0.2 0.4 0.6 0.2], ...
            'String',decodedValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FrenadomagnticoTab = uitab(app.TabGroup);
            app.FrenadomagnticoTab.Title = 'Frenado magnético';
            app.MasaSliderLabel = uilabel(app.FrenadomagnticoTab);
            app.MasaSliderLabel.Text = 'Masa';
            app.MasaSlider = uislider(app.FrenadomagnticoTab);
            app.MasaSlider.Limits = [0 0.15];
            app.MasaSlider.ValueChangedFcn = createCallbackFcn(app, @MasaSliderValueChanged, true);
            app.MasaSlider.Value = 0.01;
            app.MetalDropDownLabel = uilabel(app.FrenadomagnticoTab);
            app.MetalDropDownLabel.Text = 'Metal';
            app.MetalDropDown = uidropdown(app.FrenadomagnticoTab);
            app.MetalDropDown.Items = {'Cobre', 'Hierro', 'Platino'};
            app.MetalDropDown.ValueChangedFcn = createCallbackFcn(app, @MetalDropDownValueChanged, true);
            app.MetalDropDown.Value = 'Hierro';
            app.AlturainicialSliderLabel = uilabel(app.FrenadomagnticoTab);
            app.AlturainicialSliderLabel.Text = 'Altura inicial';
            app.AlturainicialSlider = uislider(app.FrenadomagnticoTab);
            app.AlturainicialSlider.Limits = [0.5 3];
            app.AlturainicialSlider.ValueChangedFcn = createCallbackFcn(app, @AlturainicialSliderValueChanged, true);
            app.AlturainicialSlider.Value = 0.5;
            app.RadioEditFieldLabel = uilabel(app.FrenadomagnticoTab);
            app.RadioEditFieldLabel.Text = 'Radio';
            app.RadioEditField = uieditfield(app.FrenadomagnticoTab, 'numeric');
            app.RadioEditField.Limits = [0 1];
            app.RadioEditField.ValueChangedFcn = createCallbackFcn(app, @RadioEditFieldValueChanged, true);
            app.RadioEditField.Value = 0.08;
            app.TiempoSliderLabel = uilabel(app.FrenadomagnticoTab);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.FrenadomagnticoTab);
            app.TiempoSlider.Limits = [1 40];
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempoSliderValueChanged, true);
            app.TiempoSlider.Value = 20;
            app.Graph = uipanel(app.FrenadomagnticoTab);
            app.posAxes = uiaxes(app.Graph);
            title(app.posAxes, 'Posición')
            xlabel(app.posAxes, 's')
            ylabel(app.posAxes, 'metros')
            app.velAxes = uiaxes(app.Graph);
            title(app.velAxes, 'Velocidad')
            xlabel(app.velAxes, 's')
            ylabel(app.velAxes, 'm/s')
            app.forceAxes = uiaxes(app.Graph);
            title(app.forceAxes, 'Fuerza')
            xlabel(app.forceAxes, 's')
            ylabel(app.forceAxes, 'N')
            app.accAxes = uiaxes(app.Graph);
            title(app.accAxes, 'Aceleración')
            xlabel(app.accAxes, 's')
            ylabel(app.accAxes, 'm/s^2')
            app.Button = uibutton(app.FrenadomagnticoTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.CampomagnticoTab = uitab(app.TabGroup);
            app.CampomagnticoTab.Title = 'Campo magnético';
            app.UIAxes = uiaxes(app.CampomagnticoTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimularButton = uibutton(app.CampomagnticoTab, 'state');
            app.SimularButton.ValueChangedFcn = createCallbackFcn(app, @SimularButtonValueChanged, true);
            app.SimularButton.Text = 'Simular';
            app.RadioLabel = uilabel(app.CampomagnticoTab);
            app.RadioLabel.Text = 'Radio';
            app.Radio = uieditfield(app.CampomagnticoTab, 'numeric');
            app.Radio.Value = 40;
            app.TmALabel = uilabel(app.CampomagnticoTab);
            app.TmALabel.Text = ' μ (T*m/A)';
            app.Miu = uieditfield(app.CampomagnticoTab, 'numeric');
            app.Miu.Value = 1.2566e-06;
            app.CorrienteALabel = uilabel(app.CampomagnticoTab);
            app.CorrienteALabel.Text = {'Corriente (A)'; ''};
            app.Corriente = uieditfield(app.CampomagnticoTab, 'numeric');
            app.Corriente.Value = -1;
            app.OrientacindeldipoloSwitchLabel = uilabel(app.CampomagnticoTab);
            app.OrientacindeldipoloSwitchLabel.Text = 'Orientación del dipolo';
            app.OrientacindeldipoloSwitch = uiswitch(app.CampomagnticoTab, 'slider');
            app.OrientacindeldipoloSwitch.Items = {'Horizontal', 'Vertical'};
            app.OrientacindeldipoloSwitch.Value = 'Horizontal';
            app.TipodegrficaSwitchLabel = uilabel(app.CampomagnticoTab);
            app.TipodegrficaSwitchLabel.Text = 'Tipo de gráfica';
            app.TipodegrficaSwitch = uiswitch(app.CampomagnticoTab, 'slider');
            app.TipodegrficaSwitch.Items = {'Streamline', 'Quiver'};
            app.TipodegrficaSwitch.Value = 'Streamline';
            app.Button_2 = uibutton(app.CampomagnticoTab, 'push');
            app.Button_2.Text = '?';
            app.SimuladordecadadeundipolomagnticofrenadoporunanilloLabel = uilabel(app.UIFigure);
            app.SimuladordecadadeundipolomagnticofrenadoporunanilloLabel.Text = 'Simulador de caída de un dipolo magnético frenado por un anillo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '________________________________________________________________________________________________________________________________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TercerEntregable(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end